classdef srv_app25 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SpEffcmsEditField               matlab.ui.control.NumericEditField
        SpEffcmsEditFieldLabel          matlab.ui.control.Label
        SnEffcmsEditField               matlab.ui.control.NumericEditField
        SnEffcmsEditFieldLabel          matlab.ui.control.Label
        PlotImportedButton              matlab.ui.control.Button
        ImportButton                    matlab.ui.control.Button
        iVocmVEditField                 matlab.ui.control.NumericEditField
        iVocmVEditFieldLabel            matlab.ui.control.Label
        CPDlightEditField_2             matlab.ui.control.NumericEditField
        CPDdarkEditField_2              matlab.ui.control.NumericEditField
        XcentroidnmEditField            matlab.ui.control.NumericEditField
        XcentroidnmEditFieldLabel       matlab.ui.control.Label
        ExportDefectsButton             matlab.ui.control.Button
        WorkingLamp                     matlab.ui.control.Lamp
        WorkingLampLabel                matlab.ui.control.Label
        CPDlightEditField               matlab.ui.control.NumericEditField
        CPDlightEditFieldLabel          matlab.ui.control.Label
        CPDdarkEditField                matlab.ui.control.NumericEditField
        CPDdarkEditFieldLabel           matlab.ui.control.Label
        TbulksrhsEditField              matlab.ui.control.NumericEditField
        TbulksrhsEditFieldLabel         matlab.ui.control.Label
        Gen_lightcm3EditField           matlab.ui.control.NumericEditField
        Gen_lightcm3EditFieldLabel      matlab.ui.control.Label
        estimateKPButton                matlab.ui.control.Button
        SPVcalulationsLabel             matlab.ui.control.Label
        UnderLightCheckBox              matlab.ui.control.CheckBox
        SweepmsButton                   matlab.ui.control.Button
        surfVEditField_2                matlab.ui.control.NumericEditField
        surfVEditField_2Label           matlab.ui.control.Label
        Q_geffqcm2EditField             matlab.ui.control.NumericEditField
        Q_geffqcm2EditFieldLabel        matlab.ui.control.Label
        p_s1cm3EditField_2              matlab.ui.control.NumericEditField
        p_s1cm3EditField_2Label         matlab.ui.control.Label
        n_s1cm3EditField_2              matlab.ui.control.NumericEditField
        n_s1cm3EditField_2Label         matlab.ui.control.Label
        J0sfAcm2EditField_2             matlab.ui.control.NumericEditField
        J0sfAcm2EditField_2Label        matlab.ui.control.Label
        SeffcmsEditField_2              matlab.ui.control.NumericEditField
        SeffcmsEditField_2Label         matlab.ui.control.Label
        p_s1cm3EditField                matlab.ui.control.NumericEditField
        p_s1cm3EditFieldLabel           matlab.ui.control.Label
        n_s1cm3EditField                matlab.ui.control.NumericEditField
        n_s1cm3EditFieldLabel           matlab.ui.control.Label
        surfVEditField                  matlab.ui.control.NumericEditField
        surfVEditFieldLabel             matlab.ui.control.Label
        WithMetalEffectsLabel           matlab.ui.control.Label
        DitLabel_2                      matlab.ui.control.Label
        DnorQfrangeLabel                matlab.ui.control.Label
        J0sfAcm2EditField               matlab.ui.control.NumericEditField
        J0sfAcm2EditFieldLabel          matlab.ui.control.Label
        Sp0cmsEditField                 matlab.ui.control.NumericEditField
        Sp0cmsEditFieldLabel            matlab.ui.control.Label
        Sn0cmsEditField                 matlab.ui.control.NumericEditField
        Sn0cmsEditFieldLabel            matlab.ui.control.Label
        NumSweepEditField_2             matlab.ui.control.NumericEditField
        pointsLabel                     matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        Defects1to6Panel                matlab.ui.container.Panel
        ButtonGroup                     matlab.ui.container.ButtonGroup
        NeutralButton                   matlab.ui.control.RadioButton
        ChargedButton                   matlab.ui.control.RadioButton
        DitEditField_11                 matlab.ui.control.NumericEditField
        DitEditField_13Label            matlab.ui.control.Label
        EEtEditField_6                  matlab.ui.control.NumericEditField
        EEtEditField_7Label             matlab.ui.control.Label
        pEditField_6                    matlab.ui.control.NumericEditField
        pEditField_6Label               matlab.ui.control.Label
        nEditField_7                    matlab.ui.control.NumericEditField
        nEditField_7Label               matlab.ui.control.Label
        DitEditField_12                 matlab.ui.control.NumericEditField
        DitEditField_12Label            matlab.ui.control.Label
        typeDropDown_6                  matlab.ui.control.DropDown
        typeDropDown_6Label             matlab.ui.control.Label
        pEditField_5                    matlab.ui.control.NumericEditField
        pEditField_5Label               matlab.ui.control.Label
        nEditField_6                    matlab.ui.control.NumericEditField
        nEditField_6Label               matlab.ui.control.Label
        DitEditField_10                 matlab.ui.control.NumericEditField
        DitEditField_10Label            matlab.ui.control.Label
        EEtEditField_5                  matlab.ui.control.NumericEditField
        EEtEditField_5Label             matlab.ui.control.Label
        DitEditField_9                  matlab.ui.control.NumericEditField
        DitEditField_9Label             matlab.ui.control.Label
        pEditField_4                    matlab.ui.control.NumericEditField
        pEditField_4Label               matlab.ui.control.Label
        nEditField_5                    matlab.ui.control.NumericEditField
        nEditField_5Label               matlab.ui.control.Label
        DitEditField_8                  matlab.ui.control.NumericEditField
        DitEditField_8Label             matlab.ui.control.Label
        EEtEditField_4                  matlab.ui.control.NumericEditField
        EEtEditField_4Label             matlab.ui.control.Label
        DitEditField_7                  matlab.ui.control.NumericEditField
        DitEditField_7Label             matlab.ui.control.Label
        pEditField_3                    matlab.ui.control.NumericEditField
        pEditField_3Label               matlab.ui.control.Label
        nEditField_4                    matlab.ui.control.NumericEditField
        nEditField_4Label               matlab.ui.control.Label
        DitEditField_6                  matlab.ui.control.NumericEditField
        DitEditField_6Label             matlab.ui.control.Label
        EEtEditField_3                  matlab.ui.control.NumericEditField
        EEtEditField_3Label             matlab.ui.control.Label
        DitEditField_5                  matlab.ui.control.NumericEditField
        DitEditField_5Label             matlab.ui.control.Label
        typeDropDown_5                  matlab.ui.control.DropDown
        typeDropDown_5Label             matlab.ui.control.Label
        typeDropDown_4                  matlab.ui.control.DropDown
        typeDropDown_4Label             matlab.ui.control.Label
        typeDropDown_3                  matlab.ui.control.DropDown
        typeDropDown_3Label             matlab.ui.control.Label
        pEditField_2                    matlab.ui.control.NumericEditField
        pEditField_2Label               matlab.ui.control.Label
        nEditField_3                    matlab.ui.control.NumericEditField
        nEditField_3Label               matlab.ui.control.Label
        DitEditField_4                  matlab.ui.control.NumericEditField
        DitEditField_4Label             matlab.ui.control.Label
        EEtEditField_2                  matlab.ui.control.NumericEditField
        EEtEditField_2Label             matlab.ui.control.Label
        DitEditField_3                  matlab.ui.control.NumericEditField
        DitEditField_3Label             matlab.ui.control.Label
        typeDropDown_2                  matlab.ui.control.DropDown
        typeDropDown_2Label             matlab.ui.control.Label
        pEditField                      matlab.ui.control.NumericEditField
        pEditFieldLabel                 matlab.ui.control.Label
        nEditField_2                    matlab.ui.control.NumericEditField
        nEditField_2Label               matlab.ui.control.Label
        DitEditField_2                  matlab.ui.control.NumericEditField
        DitLabel                        matlab.ui.control.Label
        EEtEditField                    matlab.ui.control.NumericEditField
        EEtEditFieldLabel               matlab.ui.control.Label
        DitEditField                    matlab.ui.control.NumericEditField
        DitEditFieldLabel               matlab.ui.control.Label
        typeDropDown                    matlab.ui.control.DropDown
        typeDropDownLabel               matlab.ui.control.Label
        SeffcmsEditField                matlab.ui.control.NumericEditField
        SeffcmsEditFieldLabel           matlab.ui.control.Label
        WaferThicknesscmEditField       matlab.ui.control.NumericEditField
        WaferThicknesscmEditFieldLabel  matlab.ui.control.Label
        SieVEditField                   matlab.ui.control.NumericEditField
        SieVEditFieldLabel              matlab.ui.control.Label
        mseVEditField                   matlab.ui.control.NumericEditField
        mseVEditFieldLabel              matlab.ui.control.Label
        mEditField_2                    matlab.ui.control.NumericEditField
        mEditField_2Label               matlab.ui.control.Label
        E_fermieVEditField              matlab.ui.control.NumericEditField
        E_fermieVEditFieldLabel         matlab.ui.control.Label
        dnmEditField                    matlab.ui.control.NumericEditField
        dnmEditFieldLabel               matlab.ui.control.Label
        rEditField_2                    matlab.ui.control.NumericEditField
        rEditField_2Label               matlab.ui.control.Label
        NumSweepEditField               matlab.ui.control.NumericEditField
        Label_3                         matlab.ui.control.Label
        minEditField_2                  matlab.ui.control.NumericEditField
        minEditField_2Label             matlab.ui.control.Label
        maxEditField_2                  matlab.ui.control.NumericEditField
        maxEditField_2Label             matlab.ui.control.Label
        qqcm2EditField                  matlab.ui.control.NumericEditField
        qqcm2EditFieldLabel             matlab.ui.control.Label
        MetalDropDown                   matlab.ui.control.DropDown
        MetalDropDownLabel              matlab.ui.control.Label
        YaxisDropDown                   matlab.ui.control.DropDown
        YaxisDropDownLabel              matlab.ui.control.Label
        maxEditField                    matlab.ui.control.NumericEditField
        maxEditFieldLabel               matlab.ui.control.Label
        minEditField                    matlab.ui.control.NumericEditField
        minEditFieldLabel               matlab.ui.control.Label
        XaxisDropDown                   matlab.ui.control.DropDown
        XaxisDropDownLabel              matlab.ui.control.Label
        Qf_interfaceqcm2EditField       matlab.ui.control.NumericEditField
        Qf_interfaceqcm2EditFieldLabel  matlab.ui.control.Label
        n1cm3EditField                  matlab.ui.control.NumericEditField
        n1cm3EditFieldLabel             matlab.ui.control.Label
        dopantField                     matlab.ui.control.NumericEditField
        Ndopant1cm3EditFieldLabel       matlab.ui.control.Label
        DielectricDropDown              matlab.ui.control.DropDown
        DielectricDropDownLabel         matlab.ui.control.Label
        ClearButton                     matlab.ui.control.Button
        ExportButton                    matlab.ui.control.Button
        pLabel                          matlab.ui.control.Label
        nLabel                          matlab.ui.control.Label
        UsLabel                         matlab.ui.control.Label
        SweepButton                     matlab.ui.control.Button
        XaxisDropDown_2                 matlab.ui.control.DropDown
        CalculateButton                 matlab.ui.control.Button
        SiButtonGroup                   matlab.ui.container.ButtonGroup
        pButton                         matlab.ui.control.RadioButton
        nButton                         matlab.ui.control.RadioButton
        InterfaceRecombinationCalculatorLabel  matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Ndop; %1/cm3 + for acceptor - for donors ---  1ohm cm=5e15
        tox; %oxide thicknes cm %%% 13.728e-6 for double layers
        DeltaWorkF; % metal to semicon work funciton diff [V]
        q=1.60e-19;%C electron charge
        epsrox=3.9;%insulator relative permitivity constant
        eps0=8.85e-14;%F/cm vacuum permitivity
        k=1.38e-23;%J/K boltazmann constant
        T=300;%K Temperature
        
        ni=9.65409e9;%1/cm3 Semicon intrinsic carrier consentration,Altermatt JAP 2003 
        phiM=4.1;%eV Metal work function
        epsrs=11.9;% Semicon relative permitivity
        chi=4.1; %V Semicon suceptibility 
        Eg=1.12; %eV Semicon gap energy
        m_e=9.1095e-31;%kg  eletron's mass
        h=6.626068e-34;% m2 kg / s plank's constant
        m_effC;% kg semicon electrons effective mass for conduction
        vth;
        phiF;
        Vt; % eV Thermal energy 
        n_0;
        p_0;
        Evector;
        Usrh;
        waferT; % wafer thickness in cm
        
        DitMatrix;
        CapXn;
        CapXp;
        DitAcc;
        DitDon;
        Vsurface;
        CPD;
        
        figAxes;
        
        MetalActive=0;
        ImportedData double % Description
    end
    
    methods (Access = public)
        
        function recalculateall(app)
            
            if strcmp(app.SiButtonGroup.SelectedObject.Text,'n')
                app.Ndop=-app.dopantField.Value;
            else
                app.Ndop=app.dopantField.Value;
            end
            
            app.Vt=(app.k*app.T)/app.q;            
            app.m_effC=0.26*app.m_e;
            app.vth=sqrt(3*app.k*app.T/app.m_effC)/1e-2;%cm/s thermal velocity
            app.phiF=-(app.Ndop/abs(app.Ndop))*app.Vt*log(abs(app.Ndop/app.ni));%eV Semicon fermi level
            app.tox=app.dnmEditField.Value*1e-7;
            app.epsrox=app.rEditField_2.Value;
            app.waferT=app.WaferThicknesscmEditField.Value;
            app.E_fermieVEditField.Value=app.phiF;
            
            
            WorkF=app.chi+app.Eg/2-app.phiF;
            app.SieVEditField.Value=WorkF;
            
            app.DeltaWorkF=app.mEditField_2.Value-WorkF;
            
            app.mseVEditField.Value=app.DeltaWorkF;
            
            if app.Ndop>0 %acceptors
                app.p_0=app.Ndop;%1/cm3 equilibrium holes  concentration ~N_a-
                app.n_0=app.ni^2/app.p_0;%1/cm3 equilibrium electrons concentration 
            else % donors
                app.n_0=-app.Ndop;%1/cm3 equilibrium holes  concentration ~N_d+
                app.p_0=app.ni^2/app.n_0;%1/cm3 equilibrium electrons concentration 
            end


           readoutDit(app)
     
           Cn=app.vth*sum(app.DitMatrix.*app.CapXn,1)';
           Cp=app.vth*sum(app.DitMatrix.*app.CapXp,1)';
           

           app.Sn0cmsEditField.Value=Cn(ceil(length(Cn)/2));
           app.Sp0cmsEditField.Value=Cp(ceil(length(Cp)/2));
           
           app.SnEffcmsEditField.Value=mean(Cn);
           app.SpEffcmsEditField.Value=mean(Cp);
           
           Delta_n=app.n1cm3EditField.Value+1e9;
           Qf=app.Qf_interfaceqcm2EditField.Value;
            
           SigmaQ=app.qqcm2EditField.Value+1e7;

           [Recombination,app.Usrh,Phis,Charge,Q_si,Q_it,J0s,iVoc,n_s,p_s]= Us0(app,Delta_n,Qf,SigmaQ,Cn,Cp);

           
           app.SeffcmsEditField.Value=Recombination./Delta_n;
           app.J0sfAcm2EditField.Value=J0s;
           app.iVocmVEditField.Value=iVoc*1000;
           app.surfVEditField.Value=Phis;
           app.n_s1cm3EditField.Value=n_s;
           app.p_s1cm3EditField.Value=p_s;
           
           % now in the presence of a floating metal contact
           app.MetalActive=1;
           
           % in this case Delta_n=1, meaning that there is no injections:
           % equilibrium conditions
           [Recombination,~,Phis,Charge,Q_si,Q_it,J0s,iVoc,n_s,p_s]= Us0(app,1,Qf,SigmaQ,Cn,Cp);
            Qf2=-(app.DeltaWorkF+Phis)*(app.eps0*app.epsrox)./(app.q*app.tox);
           app.Q_geffqcm2EditField.Value=Qf2;    
           
           app.MetalActive=0;
           
           [Recombination,~,Phis,Charge,Q_si,Q_it,J0s,iVoc,n_s,p_s]= Us0(app,Delta_n,Qf+Qf2,SigmaQ,Cn,Cp);
           app.SeffcmsEditField_2.Value=Recombination./Delta_n;
           app.J0sfAcm2EditField_2.Value=J0s;
           app.n_s1cm3EditField_2.Value=n_s;
           app.p_s1cm3EditField_2.Value=p_s;
           app.surfVEditField_2.Value=Phis;
           
           % in this case we will set the Delta_n to the steady state light
           % injected condition, and calculate Qgate under illumination
           
           app.MetalActive=1;

           [Recombination,~,Phis,Charge,Q_si,Q_it,J0s,iVoc,n_s,p_s]= Us0(app,Delta_n,Qf,SigmaQ,Cn,Cp);
            Qf2=-(app.DeltaWorkF+Phis)*(app.eps0*app.epsrox)./(app.q*app.tox);
           app.Q_geffqcm2EditField.Value=Qf2;    
           
           app.MetalActive=0;
           
           if app.UnderLightCheckBox.Value==1
           
               app.SeffcmsEditField_2.Value=Recombination./Delta_n;
               app.J0sfAcm2EditField_2.Value=J0s;
               app.n_s1cm3EditField_2.Value=n_s;
               app.p_s1cm3EditField_2.Value=p_s;
               app.surfVEditField_2.Value=Phis;
           
           
           end


           replotall(app)
        end
        
        
        function readoutDit(app)
            
            app.Evector=linspace(-app.Eg/2,app.Eg/2,50)';
            app.DitAcc=zeros(size(app.Evector));
            app.DitDon=zeros(size(app.Evector));
            app.DitMatrix=zeros(6,length(app.Evector));
            app.CapXn=zeros(6,length(app.Evector))+1e-25;
            app.CapXp=zeros(6,length(app.Evector))+1e-25;
            
            DitParams=[app.DitEditField.Value;app.EEtEditField.Value;app.DitEditField_2.Value;app.nEditField_2.Value;app.pEditField.Value;app.Eg];
            app.DitMatrix(1,:)=calcDit(app,app.typeDropDown.Value,app.Evector,DitParams);
            if ~strcmp('Inactive',app.typeDropDown.Value)
            app.CapXn(1,app.DitMatrix(1,:)>1e-8)=DitParams(4);
            app.CapXp(1,app.DitMatrix(1,:)>1e-8)=DitParams(5);
            end
            
            DitParams=[app.DitEditField_3.Value;app.EEtEditField_2.Value;app.DitEditField_4.Value;app.nEditField_3.Value;app.pEditField_2.Value;app.Eg];
            app.DitMatrix(2,:)=calcDit(app,app.typeDropDown_2.Value,app.Evector,DitParams);
            if ~strcmp('Inactive',app.typeDropDown_2.Value)
            app.CapXn(2,app.DitMatrix(2,:)>1e-8)=DitParams(4);
            app.CapXp(2,app.DitMatrix(2,:)>1e-8)=DitParams(5);
            end
            
            DitParams=[app.DitEditField_5.Value;app.EEtEditField_3.Value;app.DitEditField_6.Value;app.nEditField_4.Value;app.pEditField_3.Value;app.Eg];
            app.DitMatrix(3,:)=calcDit(app,app.typeDropDown_3.Value,app.Evector,DitParams);
            if ~strcmp('Inactive',app.typeDropDown_3.Value)
            app.CapXn(3,app.DitMatrix(3,:)>1e-8)=DitParams(4);
            app.CapXp(3,app.DitMatrix(3,:)>1e-8)=DitParams(5);
            end
            
            DitParams=[app.DitEditField_7.Value;app.EEtEditField_4.Value;app.DitEditField_8.Value;app.nEditField_5.Value;app.pEditField_4.Value;app.Eg];
            app.DitMatrix(4,:)=calcDit(app,app.typeDropDown_4.Value,app.Evector,DitParams);
            if ~strcmp('Inactive',app.typeDropDown_4.Value)
            app.CapXn(4,app.DitMatrix(4,:)>1e-8)=DitParams(4);
            app.CapXp(4,app.DitMatrix(4,:)>1e-8)=DitParams(5);
            end
            
            DitParams=[app.DitEditField_9.Value;app.EEtEditField_5.Value;app.DitEditField_10.Value;app.nEditField_6.Value;app.pEditField_5.Value;app.Eg];
            app.DitMatrix(5,:)=calcDit(app,app.typeDropDown_5.Value,app.Evector,DitParams);
            if ~strcmp('Inactive',app.typeDropDown_5.Value)
            app.CapXn(5,app.DitMatrix(5,:)>1e-8)=DitParams(4);
            app.CapXp(5,app.DitMatrix(5,:)>1e-8)=DitParams(5);
            end
            
            DitParams=[app.DitEditField_11.Value;app.EEtEditField_6.Value;app.DitEditField_12.Value;app.nEditField_7.Value;app.pEditField_6.Value;app.Eg];
            app.DitMatrix(6,:)=calcDit(app,app.typeDropDown_6.Value,app.Evector,DitParams);
            if ~strcmp('Inactive',app.typeDropDown_6.Value)
                app.CapXn(6,app.DitMatrix(6,:)>1e-8)=DitParams(4);
                app.CapXp(6,app.DitMatrix(6,:)>1e-8)=DitParams(5);
            end
            
        end
        
        function DitVectorial = calcDit(app,distype, Evec,DitParams)

            DitTop=DitParams(1);
            Etrap=DitParams(2);
            DeltaDit=DitParams(3);
%             capXn=DitParams(4);
%             capXp=DitParams(5);
%             Egap=DitParams(6);

            switch distype
                
                case 'Inactive'
                    DitVectorial=0*Evec;    
                
                case 'Don -tail'
                    
                    m_v=(log(1e8)-log(DitTop))/(DeltaDit);
                    DitVectorial=exp(((m_v)*Evec+(log(DitTop)-Etrap*(m_v))));
                    app.DitDon=app.DitDon+DitVectorial;
                    
    
                case 'Acc -tail'
                   
                    m_c=(log(1e8)-log(DitTop))/(-DeltaDit);
                    DitVectorial=exp(((m_c)*Evec+(log(DitTop)-Etrap*(m_c))));
                    app.DitAcc=app.DitAcc+DitVectorial;
                        
                case 'Acc -tophat'
                    
                    DitVectorial=DitTop*(heaviside(Evec-(Etrap-DeltaDit/2))-heaviside(Evec-(Etrap+DeltaDit/2)) )+1e-80;
                    app.DitAcc=app.DitAcc+DitVectorial;
                    
                case 'Don -tophat'
                    
                    DitVectorial=DitTop*(heaviside(Evec-(Etrap-DeltaDit/2))-heaviside(Evec-(Etrap+DeltaDit/2)) )+1e-80;
                    app.DitDon=app.DitDon+DitVectorial;
                    
                case 'Acc -gauss'
                   
                    DitVectorial=DitTop*exp(-(Evec-Etrap).^2/(2*DeltaDit^2));
                    app.DitAcc=app.DitAcc+DitVectorial;
                    
                case 'Don -gauss'
                    
                    DitVectorial=DitTop*exp(-(Evec-Etrap).^2/(2*DeltaDit^2));
                    app.DitDon=app.DitDon+DitVectorial;
            
            end
        end
        
        function replotall(app)
            yyaxis(app.UIAxes,'left')

            
            app.UIAxes.set('DefaultLineLineWidth', 1.5);
            
            plot(app.UIAxes,app.Evector,app.DitMatrix(1,:));
            hold(app.UIAxes,'on')
            plot(app.UIAxes,app.Evector,app.DitMatrix(2,:));
            plot(app.UIAxes,app.Evector,app.DitMatrix(3,:));
            plot(app.UIAxes,app.Evector,app.DitMatrix(4,:));
            plot(app.UIAxes,app.Evector,app.DitMatrix(5,:));
            plot(app.UIAxes,app.Evector,app.DitMatrix(6,:));
            plot(app.UIAxes,app.Evector,app.Usrh,'b-');
            hold(app.UIAxes,'off')
            
            yyaxis(app.UIAxes,'right')
            app.UIAxes.set('DefaultLineMarkerSize', 8);
            plot(app.UIAxes,app.Evector,app.CapXn(1,:),'.');
            hold(app.UIAxes,'on')
            plot(app.UIAxes,app.Evector,app.CapXn(2,:),'.');
            plot(app.UIAxes,app.Evector,app.CapXn(3,:),'.');
            plot(app.UIAxes,app.Evector,app.CapXn(4,:),'.');
            plot(app.UIAxes,app.Evector,app.CapXn(5,:),'.');
            plot(app.UIAxes,app.Evector,app.CapXn(6,:),'.');
            
            plot(app.UIAxes,app.Evector,app.CapXp(1,:),'.m');
            plot(app.UIAxes,app.Evector,app.CapXp(2,:),'.m');
            plot(app.UIAxes,app.Evector,app.CapXp(3,:),'.m');
            plot(app.UIAxes,app.Evector,app.CapXp(4,:),'.m');
            plot(app.UIAxes,app.Evector,app.CapXp(5,:),'.m');
            plot(app.UIAxes,app.Evector,app.CapXp(6,:),'.m');
            hold(app.UIAxes,'off')
                    
        end
        
        function [F0,F1,F2,F3,F4,F5,F6,iVoc,F7,F8]=Us0(app,Delta_n,Qf,SigmaQ,Cn,Cp)
            
            
            %excess concentration in stable region
            n_d=app.n_0+Delta_n;
            p_d=app.p_0+Delta_n;
            
            % cuasi fermi levels in stable region
            phi_n=-app.Vt*log(n_d/app.ni); 
            phi_p=app.Vt*log(p_d/app.ni); 
               
            %starting values
          
            E=app.Evector;
            
            n_1=app.ni*exp((E)/app.Vt); %1/cm3 SRH carrier concentrations at the interface
            p_1=app.ni*exp((-E)/app.Vt);
            
            Cp2=Cp;
            Cn2=Cn;
            Cr=Cp2./Cn2;
            
            if strcmp(app.ButtonGroup.SelectedObject.Text,'Charged')
                Dit_donors=app.DitDon;
                Dit_accpetors=app.DitAcc;
            else
                Dit_donors=0;Dit_accpetors=0;
            end
            
            Qsize=41;
            if Delta_n<1e13;Qsize=151;end
                

            Qf_vec=linspace(Qf-3*SigmaQ,Qf+3*SigmaQ,Qsize);
     
            PdistQ=(1./(SigmaQ*sqrt(2*pi))*exp(-(Qf_vec-Qf).^2./(2*SigmaQ^2)));
            
            PhisVar=zeros(Qsize,1);
            phiSran=[-(app.Eg/0.8+app.phiF),app.Eg/0.8-app.phiF];%initial range all bandgap
            

            for Qf_index=1:length(Qf_vec)
               
                Qerror=1e15;
                
                while min(Qerror)>1e2   

                        
                            phi_s2=linspace(phiSran(1),phiSran(2));
                            QgateEff=-(app.DeltaWorkF+phi_s2)*(app.eps0*app.epsrox)./(app.q*app.tox);
            
                            Q_Si=-sign(phi_s2).*sqrt(2*app.k*app.T*app.ni*app.epsrs*app.eps0).*sqrt(exp((phi_p-phi_s2)./app.Vt)-exp((phi_p)/app.Vt)+ ...
                                exp((phi_s2-phi_n)./app.Vt)-exp((-phi_n)/app.Vt)+phi_s2.*(app.Ndop)/(app.Vt*app.ni))/app.q;
                            n_s=n_d*exp(phi_s2/app.Vt);p_s=p_d*exp(-phi_s2/app.Vt);
                            f_a=(n_s+Cr.*p_1)./((n_s+n_1)+Cr.*(p_s+p_1));
                            Qit_d=trapz(E,(1-f_a).*Dit_donors);
                            Qit_a=-trapz(E,(f_a).*Dit_accpetors);
                            
                            Qerror=abs(Q_Si+Qit_a+Qit_d+Qf_vec(Qf_index)+QgateEff.*app.MetalActive);
                            [~,indPhi]=min(Qerror);
                            phiSran2=phiSran(2)-phiSran(1);
                            phiSran=[phi_s2(indPhi)-phiSran2/100,phi_s2(indPhi)+phiSran2/100];
                            if phiSran2<1e-9 && min(Qerror)>1e3
                                disp('possible convergence error')
                                disp(phiSran)
                            end
                

                end
                
                phiSran=[phiSran(1)-SigmaQ*10e-13,phiSran(1)+SigmaQ*10e-13];% for next val of Qf
                
                phi_s=phi_s2(indPhi);
                PhisVar(Qf_index)=phi_s;

            end
            
            
            n_1=app.ni*exp(E./app.Vt); %1/cm3 SRH carrier concentrations at the interface
            p_1=app.ni*exp(-E./app.Vt);
            
            n_s=n_d*exp(PhisVar./app.Vt);
            p_s=p_d*exp(-PhisVar./app.Vt);
            U_SRH=zeros(length(PhisVar),1);
            for i=1:length(Qf_vec)
                U_SRH(i)= trapz(E,(n_s(i).*p_s(i)-app.ni^2).*((1)./((n_s(i)+n_1)./(Cp2)+(p_s(i)+p_1)./(Cn2))).*PdistQ(i));
                if i==25
                    U_srh2=(n_s(i).*p_s(i)-app.ni^2).*((1)./((n_s(i)+n_1)./(Cp2)+(p_s(i)+p_1)./(Cn2)));
                end
            end
            
            F0=trapz(Qf_vec,U_SRH);  
            F1=U_srh2;
            
%             F2=PhisVar(ceil(Qsize/2));%surface potential in the semicon without charge fluctuations.
            F2=trapz(Qf_vec,PhisVar.*PdistQ');%surface potential in the semicon with charge fluctuations.
            
            phi_s2=F2;
            Q_Si=-sign(phi_s2).*sqrt(2*app.k*app.T*app.ni*app.epsrs*app.eps0).*sqrt(exp((phi_p-phi_s2)./app.Vt)-exp((phi_p)/app.Vt)+ ...
                    exp((phi_s2-phi_n)./app.Vt)-exp((-phi_n)/app.Vt)+phi_s2.*(app.Ndop)/(app.Vt*app.ni))/app.q;
               
            n_s=n_d*exp(phi_s2/app.Vt);p_s=p_d*exp(-phi_s2/app.Vt);
            f_a=(n_s+Cr.*p_1)./((n_s+n_1)+Cr.*(p_s+p_1));
            Qit_d=trapz(E,(1-f_a).*Dit_donors);
            Qit_a=-trapz(E,(f_a).*Dit_accpetors);
            Qerror=abs(Q_Si+Qit_a+Qit_d+Qf_vec(Qf_index));
            
            F3=Qerror;%minimised charge
            F4=Q_Si;% charge on silicon
            F5=Qit_d+Qit_a;% charge on interface
            F6=app.q*F0./(((n_s.*p_s)./(app.ni.^2)-1))*1e15;%J0s in fA/cm2
            F7=n_s;
            F8=p_s;
            iVoc=app.Vt*log((Delta_n.*(abs(app.Ndop)+Delta_n))./app.ni.^2);

        end


    end
    
    
    methods (Access = private)
        
        function [t_Aug,t_Rad]=IntrinsicTbulk(app,Ndop,Delta_n)
          
            %excess concentration in stable region
            n_d=app.n_0+Delta_n;
            p_d=app.p_0+Delta_n;
            
            
            % Richter model
            
%             Blow=4.73e-15 ; %[cm-3s-1] low injection radiative recombination probability
%             % Values from Altermatt in NUSOD'2005
%             Bmin=0.2+(0-0.2)/(1+(app.T/320).^(2.5));
%             b1=1.5e18+(1e7-1.5e18)/(1+(app.T/550).^(3.0));
%             b3=4e18+(1e9-4e18)/(1+(app.T/365).^(3.54));
%             Brel=Bmin+(1.00-Bmin)./(1+((n_d+p_d)/b1).^(0.54)+((n_d+p_d)/b3).^(1.25));
%             
%             B=Brel.*Blow; %[cm-3s-1] radiative recombination probability
%             Egnarrow=1e-3; % from J. Appl. Phys., Vol. 84, No. 7, 1 October 1998 Andreas Schenk
%             nieff=app.ni*exp(Egnarrow/(2*app.k*app.T/app.q));
%             
%             geeh=1+13*(1-tanh((app.n_0/3.3e17).^(0.66)));
%             gehh=1+7.5*(1-tanh((app.p_0/7e17).^(0.63)));
%             t_intr=(Delta_n)./((n_d.*p_d-nieff.^2).*(2.5e-31*geeh*app.n_0+8.5e-32*gehh*app.p_0+3e-29*Delta_n.^(0.92)+B));
%             t_Aug=(Delta_n)./((n_d.*p_d-nieff.^2).*(2.5e-31*geeh*app.n_0+8.5e-32*gehh*app.p_0+3e-29*Delta_n.^(0.92)));
%             t_Rad=(Delta_n)./((n_d.*p_d-nieff.^2).*(B));
                      
            
            % Niewelt model
            
            
            %excess concentration in stable region
            if app.n_0>app.p_0;n_d=app.n_0+Delta_n;p_d=0+Delta_n;
            else; n_d=0+Delta_n;p_d=app.p_0+Delta_n;
            end
                        
            Blow=4.76e-15 ; %[cm-3s-1] Nguyen
            % Values from Altermatt in NUSOD'2005
            Bmin=0.2+(0-0.2)/(1+(app.T/320).^(2.5));
            b1=2*(1.5e18+(1e7-1.5e18)/(1+(app.T/550).^(3.0)));
            b3=2*(4e18+(1e9-4e18)/(1+(app.T/365).^(3.54)));
            Brel=Bmin+(1.00-Bmin)./(1+((n_d+p_d)/b1).^(0.54)+((n_d+p_d)/b3).^(1.25));
            
            % recalculating for bandgap narrowing
            nieff2=app.ni.^2./Brel;
            if app.n_0>app.p_0
                n0eff=app.n_0;p0eff=nieff2./app.n_0;
                n_d=app.n_0+Delta_n;
                p_d=nieff2./app.n_0+Delta_n;
            else
                p0eff=app.p_0;n0eff=nieff2./app.p_0;
                n_d=nieff2./app.p_0+Delta_n;
                p_d=app.p_0+Delta_n;
            end
      
            B=Brel.*Blow; %[cm-3s-1] radiative recombination probability
            
            %photon recycling
            fPR= 0.9835 + 0.006841*log10(app.waferT)-4.554e-9.*(Delta_n).^(0.4612);
                    
            geeh=1+(4.38-1)*(1./(1+((n_d+p_d)/4e17).^(2)));
            gehh=1+(4.88-1)*(1./(1+((n_d+p_d)/4e17).^(2)));
            
            
            R_Auger=((3.41e-31*geeh.*((n_d.^2.*p_d)-(n0eff.^2.*p0eff))+1.17e-31*gehh.*((n_d.*p_d.^2)-(n0eff.*p0eff.^2))));
            
            t_Aug=(Delta_n)./(R_Auger);
            
            R_rad=((n_d.*p_d-nieff2).*(B.*(1-fPR)));
            
            t_Rad=(Delta_n)./(R_rad-0);
            
            
        end
        
       
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           app.UIAxes.FontSize=14;
           app.UIAxes2.FontSize=14;
           app.UIAxes.PlotBoxAspectRatioMode='auto';
           app.UIAxes2.PlotBoxAspectRatioMode='auto';
           
           
            app.UIAxes.YScale='log';
            app.UIAxes.YLim = [1e9 1e18];
            
            yyaxis(app.UIAxes,'right')
            app.UIAxes.YLabel.String='Capture Cross Sec (cm2)';
            app.UIAxes.YLim = [1e-18 1e-13];
            app.UIAxes.YScale='log';
            
            recalculateall(app)

        end

        % Callback function: DitEditField, DitEditField_10,
        % DitEditField_11, 
        % ...and 43 other components
        function dopantFieldValueChanged(app, event)
            recalculateall(app)
        end

        % Button pushed function: CalculateButton, SweepButton
        function CalculateButtonPushed(app, event)
            doSweep=0;

            app.WorkingLamp.Color='g';drawnow;
            
            switch event.Source.Text                    
                case 'Sweep'
                    doSweep=1;
                    if strcmp(app.XaxisDropDown_2.Value,'Charge') && app.maxEditField_2.Value>1e13
                        app.maxEditField_2.Value=1e13;
                    end
                    SweepVar=logspace(log10(app.minEditField_2.Value),log10(app.maxEditField_2.Value),app.NumSweepEditField.Value)'; 
            end
            
            Cn=app.vth*sum(app.DitMatrix.*app.CapXn,1)';
            Cp=app.vth*sum(app.DitMatrix.*app.CapXp,1)';

            sizeSweep=app.NumSweepEditField_2.Value;
            
            switch app.XaxisDropDown.Value
                case 'Delta_n'   
                    Delta_n=logspace(log10(app.minEditField.Value),log10(app.maxEditField.Value),sizeSweep)';
                    Qf=app.Qf_interfaceqcm2EditField.Value;
                    Xvar=Delta_n;
                    app.UIAxes2.XLabel.String='Excess carrier density (1/cm3)';app.UIAxes2.XScale='log';

                case 'Charge'
                    if app.maxEditField.Value>1e13
                        app.maxEditField.Value=1e13;
                    end
                    
                    Qf=app.Qf_interfaceqcm2EditField.Value+[-logspace(log10(app.maxEditField.Value),log10(app.minEditField.Value),ceil(sizeSweep))';logspace(log10(app.minEditField.Value),log10(app.maxEditField.Value),ceil(sizeSweep))' ];
                    
                    if strcmp(app.YaxisDropDown.Value,'KP_CPD at DeltaN')
                        Delta_n=[1e8;app.n1cm3EditField.Value+1e8];
                    else                        
                        Delta_n=app.n1cm3EditField.Value+1e8;
                    end
                    
                    Xvar=Qf-app.Qf_interfaceqcm2EditField.Value;
                    app.UIAxes2.XLabel.String='External Charge= Q_{total}-Q_{f-interface} (q/cm2)';app.UIAxes2.XScale='linear';
                    
                case {'Surface Voltage','KP_CPDdark'}
                    if app.maxEditField.Value>1e13
                        app.maxEditField.Value=1e13;
                    end
                    Qf=app.Qf_interfaceqcm2EditField.Value+[-logspace(log10(app.maxEditField.Value),log10(app.minEditField.Value),ceil(sizeSweep))';logspace(log10(app.minEditField.Value),log10(app.maxEditField.Value),ceil(sizeSweep))' ];
                    Delta_n=app.n1cm3EditField.Value+1e8;
                
            end
            
            if doSweep==1 && ~strcmp(app.XaxisDropDown.Value,app.XaxisDropDown_2.Value)
                switch app.XaxisDropDown_2.Value
                    case 'Delta_n' 
                        Delta_n=SweepVar;LegMatrix=cellstr(num2str(Delta_n,'%1.2e'));
                    case 'Charge'
                        Qf=[-flip(SweepVar);SweepVar];LegMatrix=cellstr(num2str(Qf,'%1.2e'));
                end
            else
                doSweep=0;
            end 


            Recombination=zeros(length(Delta_n),length(Qf));
            Phis=zeros(length(Delta_n),length(Qf));
            Charge=zeros(length(Delta_n),length(Qf));
            Seff=zeros(length(Delta_n),length(Qf));
            J0s=zeros(length(Delta_n),length(Qf));
            iVoc=zeros(length(Delta_n),length(Qf));
            Q_it=zeros(length(Delta_n),length(Qf));
            Q_si=zeros(length(Delta_n),length(Qf));
            SigmaQ=app.qqcm2EditField.Value+1e8;

            % calculate the recombination rate
           
                            
            for j=1:length(Qf)
                i=1;      
                for i=1:length(Delta_n)
                    [Recombination(i,j),~,Phis(i,j),Charge(i,j),Q_si(i,j),Q_it(i,j),J0s(i,j),iVoc(i,j),~,~]= Us0(app,Delta_n(i,1),Qf(j,1),SigmaQ,Cn,Cp);
                end
                Seff(:,j)=Recombination(:,j)./Delta_n;  
            end
                    
                    
            switch app.YaxisDropDown.Value
                case 'KP_CPD with light'
                    SPVlight=app.Gen_lightcm3EditField.Value;
                    if ~strcmp(app.XaxisDropDown.Value,'Charge')
                        errordlg('This function only work when using X-axis variable= Charge, please modify')
                        return
                    end
                    
                    if SPVlight==0;errordlg('Set a value of SPV carrier generation');return; end
                    
%                     Delta_n=1e13;
                    Delta_n=app.n1cm3EditField.Value+1e9;
                    
                    [TauAuger,TauRad]=IntrinsicTbulk(app,app.Ndop,Delta_n);
                    
                    TauRadM=ones(size(Seff));
                    TauAugerM=ones(size(Seff));
                    for i=1:length(TauRad)
                       TauRadM(i,:)=TauRad(i);
                       TauAugerM(i,:)=TauAuger(i);
                    end
                    
                    
                    TauEff=1./(1./(TauRadM)+1./TauAugerM+ 1./app.TbulksrhsEditField.Value+ 2*Seff./app.waferT);
                    GenRate=Delta_n./TauEff;
                    
                    Dn=SPVlight./GenRate;
                    
                    Delta_n2=logspace(log10(Delta_n*min(Dn)),log10(1e2*Delta_n*max(Dn)),30)';
                    
                    Recombination=zeros(length(Delta_n2),length(Qf));
                    Phis=zeros(length(Delta_n2),length(Qf));
                    Charge=zeros(length(Delta_n2),length(Qf));
                    Seff=zeros(length(Delta_n2),length(Qf));
                    J0s=zeros(length(Delta_n2),length(Qf));
                    iVoc=zeros(length(Delta_n),length(Qf));
                    Q_it=zeros(length(Delta_n2),length(Qf));
                    Q_si=zeros(length(Delta_n2),length(Qf));
                    SigmaQ=app.qqcm2EditField.Value+1e8;
                    
                    for j=1:length(Qf)
                        i=1;      
                        for i=1:length(Delta_n2)
                            [Recombination(i,j),~,Phis(i,j),Charge(i,j),Q_si(i,j),Q_it(i,j),J0s(i,j),iVoc(i,j),~,~]= Us0(app,Delta_n2(i,1),Qf(j,1),SigmaQ,Cn,Cp);
                        end
                        Seff(:,j)=Recombination(:,j)./Delta_n;  
                    end
                    
                    [TauAuger,TauRad]=IntrinsicTbulk(app,app.Ndop,Delta_n2);
                    TauRadM=ones(size(Seff));
                    TauAugerM=ones(size(Seff));
                    for i=1:length(TauRad)
                       TauRadM(i,:)=TauRad(i);
                       TauAugerM(i,:)=TauAuger(i);
                    end
                    TauEff=1./(1./(TauRadM)+1./TauAugerM+ 1./app.TbulksrhsEditField.Value+ 2*Seff./app.waferT);
                    GenRate=Delta_n2./TauEff;
                    Phis2=Qf';
                    for j=1:length(Qf)
                        Phis2(j)=interp1(Delta_n2,Phis(:,j),interp1(GenRate(:,j),Delta_n2,SPVlight)); 
                    end
                    if sum(isnan(Phis2))>0 
                        errordlg('Adjust Delta N to provide a better initial guess for Generation, using Extrapolation');
                        for j=1:length(Qf)
                        Phis2(j)=interp1(Delta_n2,Phis(:,j),interp1(GenRate(:,j),Delta_n2,SPVlight,"pchip","extrap"),"pchip","extrap"); 
                    end
                        
                    end
                   Phis=Phis2;
            end
            
            
            Qinterface=app.Qf_interfaceqcm2EditField.Value;
            app.CPD=-(app.DeltaWorkF+Phis+(app.XcentroidnmEditField.Value*1e-7*(Qf)*app.q*ones(1,length(Delta_n))/(app.eps0*app.epsrox))');
            app.Vsurface=app.DeltaWorkF+Phis+(app.tox*(Qf-Qinterface)*app.q*ones(1,length(Delta_n))/(app.eps0*app.epsrox))';                
           
            switch app.XaxisDropDown.Value
                case 'Surface Voltage'
                   
                    Xvar=app.Vsurface;
                    app.UIAxes2.XLabel.String='Dielectric surface voltage (V)';app.UIAxes2.XScale='linear';
                    
                case 'KP_CPD at DeltaN'
                    
                    Xvar=app.CPD;
                    app.UIAxes2.XLabel.String='KP contact potential difference (V)';app.UIAxes2.XScale='linear';
                    
            end
            
                    
            switch app.YaxisDropDown.Value
                case 'SRV'   
                    
                    plot(app.UIAxes2,Xvar,Seff);
                    hold(app.UIAxes2,'on')
                    
                    app.UIAxes2.YLabel.String='Seff (cm/s)';
                    app.UIAxes2.YScale='log';

                case 'Teff'
                    
                    
                    [TauAuger,TauRad]=IntrinsicTbulk(app,app.Ndop,Delta_n);
                    
                    TauRadM=ones(size(Seff));
                    TauAugerM=ones(size(Seff));
                    for i=1:length(TauRad)
                       TauRadM(i,:)=TauRad(i);
                       TauAugerM(i,:)=TauAuger(i);
                    end
                    
                    TauEff=1./(1./(TauRadM)+1./TauAugerM+ 1./app.TbulksrhsEditField.Value+ 2*Seff./app.waferT);
                    %TauEff=1./(1./(TauRadM)+1./TauAugerM+ 2*Seff./app.waferT);
                    
                    plot(app.UIAxes2,Xvar,TauEff);
                    
                    app.UIAxes2.YLabel.String='TauEff (s)';
                    app.UIAxes2.YScale='log';
                    
                case 'J0s'
                    plot(app.UIAxes2,Xvar,J0s);
                    hold(app.UIAxes2,'on')
                    
                    app.UIAxes2.YLabel.String='J0s (fA/cm^2)';
                    app.UIAxes2.YScale='log';
                
                case 'iVoc'
                    plot(app.UIAxes2,Xvar,iVoc);
                    hold(app.UIAxes2,'on')
                    
                    app.UIAxes2.YLabel.String='J0s (fA/cm^2)';
                    app.UIAxes2.YScale='log';
                
                case 'KP_CPD at DeltaN'
                    plot(app.UIAxes2,Xvar,app.CPD);
                    hold(app.UIAxes2,'on')
                    
                    app.UIAxes2.YLabel.String='KP contact potential difference (V)';
                    app.UIAxes2.YScale='linear';
                    
                case 'KP_CPD with light'
                    plot(app.UIAxes2,Xvar,app.CPD);
                    hold(app.UIAxes2,'on')
                    
                    app.UIAxes2.YLabel.String='KP contact potential difference (V)';
                    app.UIAxes2.YScale='linear';
                    
                    
            end
            hold(app.UIAxes2,'on')
            
%             app.UIAxes2.YMinorTick='on';
               if doSweep==1
                   
                   legend(app.UIAxes2,LegMatrix);
               end
            app.WorkingLamp.Color='w';
        end

        % Value changed function: MetalDropDown
        function MetalDropDownValueChanged(app, event)
            value = app.MetalDropDown.Value;
            switch value
                case 'Al'
                    app.mEditField_2.Value=4.2;
                case 'Au'
                    app.mEditField_2.Value=5.1;
                case 'PEDOT:PSS'
                    app.mEditField_2.Value=5;
                case 'Ni'
                    app.mEditField_2.Value=5.2;
                case 'Ag'
                    app.mEditField_2.Value=4.15;
            end
            recalculateall(app);
        end

        % Value changed function: XaxisDropDown_2
        function XaxisDropDown_2ValueChanged(app, event)
            value = app.XaxisDropDown_2.Value;
            
            switch value
                case 'Charge'
                    app.maxEditField_2.Value=1e13;
                    app.minEditField_2.Value=1e9;
                case 'Delta_n'
                    app.maxEditField_2.Value=1e17;
                    app.minEditField_2.Value=1e13;
                    
            end
            
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            fignew = figure(1);
            
            app.figAxes = axes(fignew);
            
            % Copy all UIAxes children, take over axes limits and aspect ratio.            
            allChildren = app.UIAxes2.XAxis.Parent.Children;
            copyobj(allChildren, app.figAxes)
            app.figAxes.XLim = app.UIAxes2.XLim;
            app.figAxes.YLim = app.UIAxes2.YLim;
            app.figAxes.XScale = app.UIAxes2.XScale;
            app.figAxes.YScale= app.UIAxes2.YScale;

            app.figAxes.DataAspectRatio = app.UIAxes2.DataAspectRatio;
            app.figAxes.NextPlot='add';
            
            xData=flip(get(app.UIAxes2.XAxis.Parent.Children,'Xdata')');
            yData=flip(get(app.UIAxes2.XAxis.Parent.Children,'Ydata')');
            if ~iscell(xData)
                assignin('base','xData',(xData));     
                assignin('base','yData',(yData));
            else
                assignin('base','xData',cell2mat(xData')');     
                assignin('base','yData',cell2mat(yData')');
            end
            open xData
            open yData

        end

        % Value changed function: XaxisDropDown, YaxisDropDown
        function YaxisDropDownValueChanged(app, event)
            value = app.YaxisDropDown.Value;
            
            switch app.XaxisDropDown.Value
                case 'Delta_n'
                    app.maxEditField.Value=1e17;
                    app.minEditField.Value=1e13;
                    
                otherwise
                    if app.maxEditField.Value>1e13
                    app.maxEditField.Value=1e13;
                    end
                    
                    if app.minEditField.Value>1e12
                        app.minEditField.Value=1e10;
                    end
            end
            
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            hold(app.UIAxes2,'off');
            plot(app.UIAxes2,0);
        end

        % Value changed function: DielectricDropDown
        function DielectricDropDownValueChanged(app, event)
            value = app.DielectricDropDown.Value;
            
            switch value
                case 'SiO2'
                    app.rEditField_2.Value=3.9;
                    app.Qf_interfaceqcm2EditField.Value=1e11;
                    
                case 'SiNx'
                    app.rEditField_2.Value=7.5;
                    app.Qf_interfaceqcm2EditField.Value=1e12;
                    
                case 'AlOx'
                    app.rEditField_2.Value=8;
                    app.Qf_interfaceqcm2EditField.Value=-1e12;
                    
                case 'a-Si:H'
                    app.rEditField_2.Value=11.5;
                    app.Qf_interfaceqcm2EditField.Value=1e10;


            end
            recalculateall(app);
        end

        % Button pushed function: SweepmsButton
        function SweepmsButtonPushed(app, event)
           readoutDit(app)
           
           SigmaQ=app.qqcm2EditField.Value+1e9;
           
           Cn=app.vth*sum(app.DitMatrix.*app.CapXn,1)';
           Cp=app.vth*sum(app.DitMatrix.*app.CapXp,1)';
           
           Delta_n=app.n1cm3EditField.Value;
           Qf=app.Qf_interfaceqcm2EditField.Value;
           
           WorkFmVar=linspace(2.5,6,50);
           WorkF=app.chi+app.Eg/2-app.phiF;
           ResultsMat=zeros(50,8);
            

            %%% need to programme here a sweep in the work funciton.
            
            for i=1:50
                 app.DeltaWorkF=WorkFmVar(i)-WorkF;

                 app.MetalActive=1;
%                [~,~,Phis,~,~,~,~,~,~]= Us0(app,1,Qf,SigmaQ,Cn,Cp);
               [Recombination,~,Phis,Charge,Q_si,Q_it,J0s,iVoc,n_s,p_s]= Us0(app,Delta_n,Qf,SigmaQ,Cn,Cp);
               
               Qf2=-(app.DeltaWorkF+Phis)*(app.eps0*app.epsrox)./(app.q*app.tox);
               
               app.MetalActive=0;

%                [Recombination,~,Phis,Charge,Q_si,Q_it,J0s,n_s,p_s]= Us0(app,Delta_n,Qf+Qf2,SigmaQ,Cn,Cp);
               Seff=Recombination./Delta_n;
               ResultsMat(i,:)=[Seff,Phis,Charge,Q_si,Q_it,J0s,n_s,p_s];
                
            end
            plot(app.UIAxes2,WorkFmVar,ResultsMat(:,6));
            hold(app.UIAxes2,'on')
            
            app.UIAxes2.YLabel.String='J0s (fA/cm^2)';
            app.UIAxes2.YScale='log';
            app.UIAxes2.XLabel.String='Metal Work Function \Phi_m (eV)';app.UIAxes2.XScale='linear';
            
            assignin('base','ResultsMat',[ResultsMat,WorkFmVar']); 
            open ResultsMat
            
            app.DeltaWorkF=app.mEditField_2.Value-WorkF;% put DeltaWF back to normal
           
        end

        % Button pushed function: estimateKPButton
        function estimateKPButtonPushed(app, event)
            
            app.WorkingLamp.Color='g';drawnow;
            
            Cn=app.vth*sum(app.DitMatrix.*app.CapXn,1)';
            Cp=app.vth*sum(app.DitMatrix.*app.CapXp,1)';
              
            sizeSweep=app.NumSweepEditField_2.Value;
            Dit_factor=logspace(-1,1,sizeSweep);
            Dit_donors0=app.DitDon;
            Dit_acceptor0=app.DitAcc;
            
            if app.maxEditField.Value>1e13
                app.maxEditField.Value=1e13;
            end
            
            Qf=[-logspace(log10(app.maxEditField.Value),log10(app.minEditField.Value),ceil(sizeSweep))';logspace(log10(app.minEditField.Value),log10(app.maxEditField.Value),ceil(sizeSweep))' ];
            % in the dark first
            Delta_n=1e1;
            
            Recombination=zeros(sizeSweep,length(Qf));
            Phis=zeros(sizeSweep,length(Qf));
            Charge=zeros(sizeSweep,length(Qf));
            Seff=zeros(sizeSweep,length(Qf));
            J0s=zeros(sizeSweep,length(Qf));
            Q_it=zeros(sizeSweep,length(Qf));
            Q_si=zeros(sizeSweep,length(Qf));
            SigmaQ=app.qqcm2EditField.Value+1e8;

            % calculate the recombination rate
            for j=1:length(Qf)
                i=1;      
                for i=1:sizeSweep
                    app.DitDon=Dit_donors0.*Dit_factor(i);
                    app.DitAcc=Dit_acceptor0.*Dit_factor(i);
                    [Recombination(i,j),~,Phis(i,j),Charge(i,j),Q_si(i,j),Q_it(i,j),J0s(i,j),~,~,~]= Us0(app,Delta_n,Qf(j,1),SigmaQ,Cn.*Dit_factor(i),Cp.*Dit_factor(i));
                end
                Seff(:,j)=Recombination(:,j)./Delta_n;  
            end
            
            CPDdark=-(app.DeltaWorkF+Phis+(app.XcentroidnmEditField.Value*1e-7*(Qf)*app.q*ones(1,length(Delta_n))/(app.eps0*app.epsrox))');
                      
            % under illumination equivalent to Delta_n generation
            
            Delta_n=app.n1cm3EditField.Value;
            
            Recombination=zeros(sizeSweep,length(Qf));
            Phis=zeros(sizeSweep,length(Qf));
            Charge=zeros(sizeSweep,length(Qf));
            Seff=zeros(sizeSweep,length(Qf));
            J0s=zeros(sizeSweep,length(Qf));
            Q_it=zeros(sizeSweep,length(Qf));
            Q_si=zeros(sizeSweep,length(Qf));
            SigmaQ=app.qqcm2EditField.Value+1e8;

            % calculate the recombination rate
            for j=1:length(Qf)
                i=1;      
                for i=1:sizeSweep
                    app.DitDon=Dit_donors0.*Dit_factor(i);
                    app.DitAcc=Dit_acceptor0.*Dit_factor(i);
                    [Recombination(i,j),~,Phis(i,j),Charge(i,j),Q_si(i,j),Q_it(i,j),J0s(i,j),~,~,~]= Us0(app,Delta_n,Qf(j,1),SigmaQ,Cn.*Dit_factor(i),Cp.*Dit_factor(i));
                end
                Seff(:,j)=Recombination(:,j)./Delta_n;  
            end
            
            CPDlight=-(app.DeltaWorkF+Phis+(app.XcentroidnmEditField.Value*1e-7*(Qf)*app.q*ones(1,length(Delta_n))/(app.eps0*app.epsrox))');
                      
            Ditvec=sum(app.DitMatrix,1)';
            Dit_mg=Ditvec(25).*Dit_factor;
            
            figure;
%% CPD dark                  
            
            figAxes2=subplot(2,3,1);
            surf(figAxes2,Dit_mg,Qf(Qf>0),CPDdark(:,(Qf>0))','EdgeColor','none');view(2);
            
           ylabel(figAxes2,'+Qf (q/cm^2)');
            set (gca, 'YScale', 'log') ;set (gca, 'XScale', 'log') ;title('CPD_{dark} ' )
            hold on;
            contour(Dit_mg,Qf,CPDdark', [app.CPDdarkEditField.Value,app.CPDdarkEditField_2.Value],'r');
            colorbar; ylim(gca,[app.minEditField.Value,app.maxEditField.Value]);
            xlim(gca,[min(Dit_mg),max(Dit_mg)]);
            set(gca, 'Layer', 'top','XMinorTick','on','yMinorTick','on');grid off; box on;
            caxis([min(CPDdark,[],'all') max(CPDdark,[],'all')]);
            
            figAxes2b=subplot(2,3,4);
            surf(figAxes2b,Dit_mg,-Qf(Qf<0),CPDdark(:,(Qf<0))','EdgeColor','none');view(2);
            ylabel(figAxes2b,'-Qf (q/cm^2)');set (gca,'YDir','reverse');
            xlabel(figAxes2b,'Dit at mg (/cm^2)');%set(gca,'XScale','log');xticks([1e10,1e11,1e12,1e13]);
            set (gca, 'YScale', 'log') ;set (gca, 'XScale', 'log');
            hold on;
            contour(Dit_mg,-Qf,CPDdark', [app.CPDdarkEditField.Value,app.CPDdarkEditField_2.Value],'r');
            colorbar;ylim(gca,[app.minEditField.Value,app.maxEditField.Value]);
            xlim(gca,[min(Dit_mg),max(Dit_mg)]);
            set(gca, 'Layer', 'top','XMinorTick','on','yMinorTick','on');grid off; box on;
            caxis([min(CPDdark,[],'all') max(CPDdark,[],'all')]);
            
%% CPD light
            figAxes3=subplot(2,3,2);
            surf(figAxes3,Dit_mg,Qf(Qf>0),CPDlight(:,(Qf>0))','EdgeColor','none');view(2);
            
            
            set (gca, 'YScale', 'log') ;set (gca, 'XScale', 'log') ;title('CPD_{light} ' )
            hold on;
            contour(Dit_mg,Qf,CPDlight', [app.CPDlightEditField.Value,app.CPDlightEditField_2.Value],'g');
            colorbar; ylim(gca,[app.minEditField.Value,app.maxEditField.Value]);
            xlim(gca,[min(Dit_mg),max(Dit_mg)]);
            set(gca, 'Layer', 'top','XMinorTick','on','yMinorTick','on');grid off; box on;
            caxis([min(CPDlight,[],'all') max(CPDlight,[],'all')]);
            
            figAxes3b=subplot(2,3,5);
            surf(figAxes3b,Dit_mg,-Qf(Qf<0),CPDlight(:,(Qf<0))','EdgeColor','none');view(2);
            set (gca,'YDir','reverse');
            xlabel(figAxes3b,'Dit at mg (/cm^2)');%set(gca,'XScale','log');xticks([1e10,1e11,1e12,1e13]);
            set (gca, 'YScale', 'log') ;set (gca, 'XScale', 'log');
            hold on;
            contour(Dit_mg,-Qf,CPDlight', [app.CPDlightEditField.Value,app.CPDlightEditField_2.Value],'g');
            colorbar;ylim(gca,[app.minEditField.Value,app.maxEditField.Value]);
            xlim(gca,[min(Dit_mg),max(Dit_mg)]);
            set(gca, 'Layer', 'top','XMinorTick','on','yMinorTick','on');grid off; box on;
            caxis([min(CPDlight,[],'all') max(CPDlight,[],'all')]);

%% CPD SPV
           SPV=CPDdark-CPDlight;
            figAxes4=subplot(2,3,3);
            surf(figAxes4,Dit_mg,Qf(Qf>0),SPV(:,(Qf>0))','EdgeColor','none');view(2);
            set (gca, 'YScale', 'log') ;set (gca, 'XScale', 'log') ;title('SPV=CPD_{dark}-CPD_{light}' )
            set(gca, 'Layer', 'top','XMinorTick','on','yMinorTick','on');grid off; box on;
            hold on;
            
            CPDdarkdev=abs(app.CPDdarkEditField.Value-app.CPDdarkEditField_2.Value);
            contour(Dit_mg,Qf,SPV',(mean([app.CPDdarkEditField.Value,app.CPDdarkEditField_2.Value])-mean([app.CPDlightEditField.Value,app.CPDlightEditField_2.Value]))+ ...
                [-CPDdarkdev/2,CPDdarkdev/2],'k');
            contour(Dit_mg,Qf,CPDdark', [app.CPDdarkEditField.Value,app.CPDdarkEditField_2.Value],'r');
            colorbar; ylim(gca,[app.minEditField.Value,app.maxEditField.Value]);
            xlim(gca,[min(Dit_mg),max(Dit_mg)]);
            
            caxis([min(SPV,[],'all') max(SPV,[],'all')]);
            
            figAxes4b=subplot(2,3,6);
            surf(figAxes4b,Dit_mg,-Qf(Qf<0),SPV(:,(Qf<0))','EdgeColor','none');view(2);
            set (gca,'YDir','reverse');
            xlabel(figAxes4b,'Dit at mg (/cm^2)');
            set (gca, 'YScale', 'log') ;set (gca, 'XScale', 'log');
            hold on;
            colorbar;ylim(gca,[app.minEditField.Value,app.maxEditField.Value]);
            xlim(gca,[min(Dit_mg),max(Dit_mg)]);
            set(gca, 'Layer', 'top','XMinorTick','on','yMinorTick','on');grid off; box on;
            contour(Dit_mg,-Qf,CPDdark', [app.CPDdarkEditField.Value,app.CPDdarkEditField_2.Value],'r');
            contour(Dit_mg,-Qf,SPV',(mean([app.CPDdarkEditField.Value,app.CPDdarkEditField_2.Value])-mean([app.CPDlightEditField.Value,app.CPDlightEditField_2.Value]))+ ...
                [-CPDdarkdev/2,CPDdarkdev/2],'k');
            
            caxis([min(SPV,[],'all') max(SPV,[],'all')]);


% return the interface states to their original values:
            app.DitDon=Dit_donors0;
            app.DitAcc=Dit_acceptor0;
            
            app.WorkingLamp.Color='w';
            
            
        end

        % Button pushed function: ExportDefectsButton
        function ExportDefectsButtonPushed(app, event)
            fignew = figure;
            
            app.figAxes = axes(fignew);
            
            Dit=sum(app.DitMatrix,1)';
            Capxn=sum(app.CapXn,1)';
            Capxp=sum(app.CapXp,1)';
            yyaxis left
            semilogy(app.figAxes,app.Evector,Dit);
            xlabel(app.figAxes,' Energy (eV)');
            ylabel(app.figAxes,'Dit (eV^{-1}cm^{-2})');
            
            yyaxis right
            semilogy(app.figAxes,app.Evector,Capxn,app.Evector,Capxp);
            ylabel(app.figAxes,'\sigma_n  ,  \sigma_p (cm^2)');
            legend({'Dit','CapXsecN','CapXsecP'})
            
            assignin('base','Dit',Dit);assignin('base','Capxn',Capxn);assignin('base','Capxp',Capxp);    


           Cn=app.vth*sum(app.DitMatrix.*app.CapXn,1)';
           Cp=app.vth*sum(app.DitMatrix.*app.CapXp,1)';
    
            
            fignew2 = figure;
            app.figAxes = axes(fignew2);
            semilogy(app.figAxes,app.Evector,Cn,app.Evector,Cp);

        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            [filename,pathname] = uigetfile({'*.xlsx';'*.txt'},'Please select a 2-column X-Y formatted file');
            fullname = fullfile(pathname,filename);
            try
                app.ImportedData = importdata(fullname);
                plot(app.UIAxes2,app.ImportedData(1:end,1),app.ImportedData(1:end,2),'LineStyle','none','Marker','o');
                hold(app.UIAxes2,'on');
            catch ME
                errordlg('ME.identifier');
            end


        end

        % Button pushed function: PlotImportedButton
        function PlotImportedButtonPushed(app, event)
                plot(app.UIAxes2,app.ImportedData(1:end,1),0.000001*app.ImportedData(1:end,2),'LineStyle','none','Marker','o');
                hold(app.UIAxes2,'on');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'Energy (eV)')
            ylabel(app.UIAxes, 'D_{it} (1/cm^{2}eV) and U_s(1/cm2.s.eV)')
            app.UIAxes.XLim = [-0.56 0.56];
            app.UIAxes.YLim = [100000000 1e+17];
            app.UIAxes.XTick = [-0.6 -0.4 -0.2 0 0.2 0.4 0.6];
            app.UIAxes.YScale = 'log';
            app.UIAxes2 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.InterfaceRecombinationCalculatorLabel = uilabel(app.UIFigure);
            app.InterfaceRecombinationCalculatorLabel.Text = 'Interface Recombination Calculator';
            app.SiButtonGroup = uibuttongroup(app.UIFigure);
            app.SiButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @dopantFieldValueChanged, true);
            app.SiButtonGroup.Title = 'Si';
            app.nButton = uiradiobutton(app.SiButtonGroup);
            app.nButton.Text = 'n';
            app.nButton.Value = true;
            app.pButton = uiradiobutton(app.SiButtonGroup);
            app.pButton.Text = 'p';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.XaxisDropDown_2 = uidropdown(app.UIFigure);
            app.XaxisDropDown_2.Items = {'Delta_n', 'Charge'};
            app.XaxisDropDown_2.ValueChangedFcn = createCallbackFcn(app, @XaxisDropDown_2ValueChanged, true);
            app.XaxisDropDown_2.Value = 'Delta_n';
            app.SweepButton = uibutton(app.UIFigure, 'push');
            app.SweepButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.SweepButton.Text = 'Sweep';
            app.UsLabel = uilabel(app.UIFigure);
            app.UsLabel.Text = 'Us ';
            app.nLabel = uilabel(app.UIFigure);
            app.nLabel.Text = 'σn';
            app.pLabel = uilabel(app.UIFigure);
            app.pLabel.Text = 'σp';
            app.ExportButton = uibutton(app.UIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.DielectricDropDownLabel = uilabel(app.UIFigure);
            app.DielectricDropDownLabel.Text = 'Dielectric';
            app.DielectricDropDown = uidropdown(app.UIFigure);
            app.DielectricDropDown.Items = {'SiO2', 'SiNx', 'AlOx', 'a-Si:H'};
            app.DielectricDropDown.ValueChangedFcn = createCallbackFcn(app, @DielectricDropDownValueChanged, true);
            app.DielectricDropDown.Value = 'SiO2';
            app.Ndopant1cm3EditFieldLabel = uilabel(app.UIFigure);
            app.Ndopant1cm3EditFieldLabel.Text = 'Ndopant (1/cm3)';
            app.dopantField = uieditfield(app.UIFigure, 'numeric');
            app.dopantField.Limits = [1000000000000 1e+20];
            app.dopantField.ValueDisplayFormat = '%2.2e';
            app.dopantField.ValueChangedFcn = createCallbackFcn(app, @dopantFieldValueChanged, true);
            app.dopantField.Value = 5e+15;
            app.n1cm3EditFieldLabel = uilabel(app.UIFigure);
            app.n1cm3EditFieldLabel.Text = 'Δn  (1/cm3)=';
            app.n1cm3EditField = uieditfield(app.UIFigure, 'numeric');
            app.n1cm3EditField.Limits = [0 1e+20];
            app.n1cm3EditField.ValueDisplayFormat = '%2.2e';
            app.n1cm3EditField.ValueChangedFcn = createCallbackFcn(app, @dopantFieldValueChanged, true);
            app.n1cm3EditField.Value = 1e+15;
            app.Qf_interfaceqcm2EditFieldLabel = uilabel(app.UIFigure);
            app.Qf_interfaceqcm2EditFieldLabel.Text = 'Qf_interface(q/cm2):';
            app.Qf_interfaceqcm2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Qf_interfaceqcm2EditField.Limits = [-10000000000000 10000000000000];
            app.Qf_interfaceqcm2EditField.ValueDisplayFormat = '%1.1e';
            app.Qf_interfaceqcm2EditField.ValueChangedFcn = createCallbackFcn(app, @dopantFieldValueChanged, true);
            app.Qf_interfaceqcm2EditField.Value = 100000000000;
            app.XaxisDropDownLabel = uilabel(app.UIFigure);
            app.XaxisDropDownLabel.Text = 'X-axis';
            app.XaxisDropDown = uidropdown(app.UIFigure);
            app.XaxisDropDown.Items = {'Delta_n', 'Charge', 'Surface Voltage', 'KP_CPD at DeltaN'};
            app.XaxisDropDown.ValueChangedFcn = createCallbackFcn(app, @YaxisDropDownValueChanged, true);
            app.XaxisDropDown.Value = 'Delta_n';
            app.minEditFieldLabel = uilabel(app.UIFigure);
            app.minEditFieldLabel.Text = 'min';
            app.minEditField = uieditfield(app.UIFigure, 'numeric');
            app.minEditField.Limits = [100000000 1e+19];
            app.minEditField.ValueDisplayFormat = '%1.1e';
            app.minEditField.Value = 10000000000;
            app.maxEditFieldLabel = uilabel(app.UIFigure);
            app.maxEditFieldLabel.Text = 'max';
            app.maxEditField = uieditfield(app.UIFigure, 'numeric');
            app.maxEditField.Limits = [10000000000 1e+18];
            app.maxEditField.ValueDisplayFormat = '%1.1e';
            app.maxEditField.Value = 10000000000000;
            app.YaxisDropDownLabel = uilabel(app.UIFigure);
            app.YaxisDropDownLabel.Text = 'Y-axis';
            app.YaxisDropDown = uidropdown(app.UIFigure);
            app.YaxisDropDown.Items = {'SRV', 'Teff', 'J0s', 'iVoc', 'KP_CPD at DeltaN', 'KP_CPD with light'};
            app.YaxisDropDown.ValueChangedFcn = createCallbackFcn(app, @YaxisDropDownValueChanged, true);
            app.YaxisDropDown.Value = 'SRV';
            app.MetalDropDownLabel = uilabel(app.UIFigure);
            app.MetalDropDownLabel.Text = 'Metal';
            app.MetalDropDown = uidropdown(app.UIFigure);
            app.MetalDropDown.Items = {'Al', 'Au', 'Ni', 'Ag', 'PEDOT:PSS'};
            app.MetalDropDown.ValueChangedFcn = createCallbackFcn(app, @MetalDropDownValueChanged, true);
            app.MetalDropDown.Value = 'Au';
            app.qqcm2EditFieldLabel = uilabel(app.UIFigure);
            app.qqcm2EditFieldLabel.Text = 'σ_q (q/cm2)';
            app.qqcm2EditField = uieditfield(app.UIFigure, 'numeric');
            app.qqcm2EditField.ValueChangedFcn = createCallbackFcn(app, @dopantFieldValueChanged, true);
            app.maxEditField_2Label = uilabel(app.UIFigure);
            app.maxEditField_2Label.Text = 'max';
            app.maxEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.maxEditField_2.Limits = [10000000000 1e+18];
            app.maxEditField_2.ValueDisplayFormat = '%1.1e';
            app.maxEditField_2.Value = 1e+17;
            app.minEditField_2Label = uilabel(app.UIFigure);
            app.minEditField_2Label.Text = 'min';
            app.minEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.minEditField_2.Limits = [1000000000 1e+18];
            app.minEditField_2.ValueDisplayFormat = '%1.1e';
            app.minEditField_2.Value = 10000000000000;
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '#';
            app.NumSweepEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumSweepEditField.Limits = [1 50];
            app.NumSweepEditField.ValueDisplayFormat = '%.0f';
            app.NumSweepEditField.Value = 3;
            app.rEditField_2Label = uilabel(app.UIFigure);
            app.rEditField_2Label.Text = 'Ɛr';
            app.rEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.rEditField_2.Limits = [1 15];
            app.rEditField_2.ValueChangedFcn = createCallbackFcn(app, @dopantFieldValueChanged, true);
            app.rEditField_2.Value = 3.9;
            app.dnmEditFieldLabel = uilabel(app.UIFigure);
            app.dnmEditFieldLabel.Text = 'd (nm)';
            app.dnmEditField = uieditfield(app.UIFigure, 'numeric');
            app.dnmEditField.Limits = [1 1000];
            app.dnmEditField.ValueChangedFcn = createCallbackFcn(app, @dopantFieldValueChanged, true);
            app.dnmEditField.Value = 100;
            app.E_fermieVEditFieldLabel = uilabel(app.UIFigure);
            app.E_fermieVEditFieldLabel.Text = 'E_fermi (eV)';
            app.E_fermieVEditField = uieditfield(app.UIFigure, 'numeric');
            app.E_fermieVEditField.ValueDisplayFormat = '%2.2f';
            app.E_fermieVEditField.Editable = 'off';
            app.mEditField_2Label = uilabel(app.UIFigure);
            app.mEditField_2Label.Text = 'Φm';
            app.mEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.mEditField_2.ValueChangedFcn = createCallbackFcn(app, @dopantFieldValueChanged, true);
            app.mEditField_2.Value = 5.1;
            app.mseVEditFieldLabel = uilabel(app.UIFigure);
            app.mseVEditFieldLabel.Text = 'ΔΦms (eV)';
            app.mseVEditField = uieditfield(app.UIFigure, 'numeric');
            app.mseVEditField.ValueDisplayFormat = '%2.2f';
            app.mseVEditField.Editable = 'off';
            app.SieVEditFieldLabel = uilabel(app.UIFigure);
            app.SieVEditFieldLabel.Text = 'Φ_Si (eV)';
            app.SieVEditField = uieditfield(app.UIFigure, 'numeric');
            app.SieVEditField.ValueDisplayFormat = '%2.2f';
            app.SieVEditField.Editable = 'off';
            app.WaferThicknesscmEditFieldLabel = uilabel(app.UIFigure);
            app.WaferThicknesscmEditFieldLabel.Text = 'Wafer Thickness (cm):';
            app.WaferThicknesscmEditField = uieditfield(app.UIFigure, 'numeric');
            app.WaferThicknesscmEditField.Limits = [0.001 1];
            app.WaferThicknesscmEditField.ValueChangedFcn = createCallbackFcn(app, @dopantFieldValueChanged, true);
            app.WaferThicknesscmEditField.Value = 0.02;
            app.SeffcmsEditFieldLabel = uilabel(app.UIFigure);
            app.SeffcmsEditFieldLabel.Text = 'Seff (cm/s)';
            app.SeffcmsEditField = uieditfield(app.UIFigure, 'numeric');
            app.SeffcmsEditField.ValueDisplayFormat = '%2.4f';
            app.SeffcmsEditField.Editable = 'off';
            app.Defects1to6Panel = uipanel(app.UIFigure);
            app.Defects1to6Panel.Title = 'Defects 1 to 6';
            app.typeDropDownLabel = uilabel(app.Defects1to6Panel);
            app.typeDropDownLabel.Visible = 'off';
            app.typeDropDownLabel.Text = 'type';
            app.typeDropDown = uidropdown(app.Defects1to6Panel);
            app.typeDropDown.Items = {'Inactive', 'Don -tail', 'Acc -tail', 'Acc -tophat', 'Don -tophat', 'Acc -gauss', 'Don -gauss'};
            app.typeDropDown.ValueChangedFcn = createCallbackFcn(app, @dopantFieldValueChanged, true);
            app.typeDropDown.Value = 'Don -tail';
            app.DitEditFieldLabel = uilabel(app.Defects1to6Panel);
            app.DitEditFieldLabel.Text = 'Dit';
            app.DitEditField = uieditfield(app.Defects1to6Panel, 'numeric');
            app.DitEditField.Limits = [10000000 1e+18];
            app.DitEditField.ValueChangedFcn = createCallbackFcn(app, @dopantFieldValueChanged, true);
            app.DitEditField.Value = 100000000000000;
            app.EEtEditFieldLabel = uilabel(app.Defects1to6Panel);
            app.EEtEditFieldLabel.Text = 'E-Et';
            app.EEtEditField = uieditfield(app.Defects1to6Panel, 'numeric');
            app.EEtEditField.Limits = [-1 1];
            app.EEtEditField.ValueChangedFcn = createCallbackFcn(app, @dopantFieldValueChanged, true);
            app.EEtEditField.Value = -0.56;
            app.DitLabel = uilabel(app.Defects1to6Panel);
            app.DitLabel.Text = 'ΔDit';
            app.DitEditField_2 = uieditfield(app.Defects1to6Panel, 'numeric');
            app.DitEditField_2.Limits = [-2 2];
            app.DitEditField_2.ValueChangedFcn = createCallbackFcn(app, @dopantFieldValueChanged, true);
            app.DitEditField_2.Value = 0.3;
            app.nEditField_2Label = uilabel(app.Defects1to6Panel);
            app.nEditField_2Label.Text = 'σ_n';
            app.nEditField_2 = uieditfield(app.Defects1to6Panel, 'numeric');
            app.nEditField_2.Limits = [1e-20 1e-10];
            app.nEditField_2.ValueChangedFcn = createCallbackFcn(app, @dopantFieldValueChanged, true);
            app.nEditField_2.Value = 2e-18;
            app.pEditFieldLabel = uilabel(app.Defects1to6Panel);
            app.pEditFieldLabel.Text = 'σ_p';
            app.pEditField = uieditfield(app.Defects1to6Panel, 'numeric');
            app.pEditField.Limits = [1e-20 1e-10];
            app.pEditField.ValueChangedFcn = createCallbackFcn(app, @dopantFieldValueChanged, true);
            app.pEditField.Value = 2e-18;
            app.typeDropDown_2Label = uilabel(app.Defects1to6Panel);
            app.typeDropDown_2Label.Visible = 'off';
            app.typeDropDown_2Label.Text = 'type';
            app.typeDropDown_2 = uidropdown(app.Defects1to6Panel);
            app.typeDropDown_2.Items = {'Inactive', 'Don -tail', 'Acc -tail', 'Acc -tophat', 'Don -tophat', 'Acc -gauss', 'Don -gauss'};
            app.typeDropDown_2.ValueChangedFcn = createCallbackFcn(app, @dopantFieldValueChanged, true);
            app.typeDropDown_2.Value = 'Don -tophat';
            app.DitEditField_3Label = uilabel(app.Defects1to6Panel);
            app.DitEditField_3Label.Text = 'Dit';
            app.DitEditField_3 = uieditfield(app.Defects1to6Panel, 'numeric');
            app.DitEditField_3.Limits = [10000000 1e+18];
            app.DitEditField_3.ValueChangedFcn = createCallbackFcn(app, @dopantFieldValueChanged, true);
            app.DitEditField_3.Value = 10000000000;
            app.EEtEditField_2Label = uilabel(app.Defects1to6Panel);
            app.EEtEditField_2Label.Text = 'E-Et';
            app.EEtEditField_2 = uieditfield(app.Defects1to6Panel, 'numeric');
            app.EEtEditField_2.Limits = [-1 1];
            app.EEtEditField_2.ValueChangedFcn = createCallbackFcn(app, @dopantFieldValueChanged, true);
            app.EEtEditField_2.Value = -0.2;
            app.DitEditField_4Label = uilabel(app.Defects1to6Panel);
            app.DitEditField_4Label.Text = 'ΔDit';
            app.DitEditField_4 = uieditfield(app.Defects1to6Panel, 'numeric');
            app.DitEditField_4.Limits = [-2 2];
            app.DitEditField_4.ValueChangedFcn = createCallbackFcn(app, @dopantFieldValueChanged, true);
            app.DitEditField_4.Value = 0.4;
            app.nEditField_3Label = uilabel(app.Defects1to6Panel);
            app.nEditField_3Label.Text = 'σ_n';
            app.nEditField_3 = uieditfield(app.Defects1to6Panel, 'numeric');
            app.nEditField_3.Limits = [1e-20 1e-10];
            app.nEditField_3.ValueChangedFcn = createCallbackFcn(app, @dopantFieldValueChanged, true);
            app.nEditField_3.Value = 1e-15;
            app.pEditField_2Label = uilabel(app.Defects1to6Panel);
            app.pEditField_2Label.Text = 'σ_p';
            app.pEditField_2 = uieditfield(app.Defects1to6Panel, 'numeric');
            app.pEditField_2.Limits = [1e-20 1e-10];
            app.pEditField_2.ValueChangedFcn = createCallbackFcn(app, @dopantFieldValueChanged, true);
            app.pEditField_2.Value = 1e-16;
            app.typeDropDown_3Label = uilabel(app.Defects1to6Panel);
            app.typeDropDown_3Label.Visible = 'off';
            app.typeDropDown_3Label.Text = 'type';
            app.typeDropDown_3 = uidropdown(app.Defects1to6Panel);
            app.typeDropDown_3.Items = {'Inactive', 'Don -tail', 'Acc -tail', 'Acc -tophat', 'Don -tophat', 'Acc -gauss', 'Don -gauss'};
            app.typeDropDown_3.ValueChangedFcn = createCallbackFcn(app, @dopantFieldValueChanged, true);
            app.typeDropDown_3.Value = 'Acc -tophat';
            app.typeDropDown_4Label = uilabel(app.Defects1to6Panel);
            app.typeDropDown_4Label.Visible = 'off';
            app.typeDropDown_4Label.Text = 'type';
            app.typeDropDown_4 = uidropdown(app.Defects1to6Panel);
            app.typeDropDown_4.Items = {'Inactive', 'Don -tail', 'Acc -tail', 'Acc -tophat', 'Don -tophat', 'Acc -gauss', 'Don -gauss'};
            app.typeDropDown_4.ValueChangedFcn = createCallbackFcn(app, @dopantFieldValueChanged, true);
            app.typeDropDown_4.Value = 'Acc -tail';
            app.typeDropDown_5Label = uilabel(app.Defects1to6Panel);
            app.typeDropDown_5Label.Visible = 'off';
            app.typeDropDown_5Label.Text = 'type';
            app.typeDropDown_5 = uidropdown(app.Defects1to6Panel);
            app.typeDropDown_5.Items = {'Inactive', 'Don -tail', 'Acc -tail', 'Acc -tophat', 'Don -tophat', 'Acc -gauss', 'Don -gauss'};
            app.typeDropDown_5.ValueChangedFcn = createCallbackFcn(app, @dopantFieldValueChanged, true);
            app.typeDropDown_5.Value = 'Inactive';
            app.DitEditField_5Label = uilabel(app.Defects1to6Panel);
            app.DitEditField_5Label.Text = 'Dit';
            app.DitEditField_5 = uieditfield(app.Defects1to6Panel, 'numeric');
            app.DitEditField_5.Limits = [10000000 1e+18];
            app.DitEditField_5.ValueChangedFcn = createCallbackFcn(app, @dopantFieldValueChanged, true);
            app.DitEditField_5.Value = 10000000000;
            app.EEtEditField_3Label = uilabel(app.Defects1to6Panel);
            app.EEtEditField_3Label.Text = 'E-Et';
            app.EEtEditField_3 = uieditfield(app.Defects1to6Panel, 'numeric');
            app.EEtEditField_3.Limits = [-1 1];
            app.EEtEditField_3.ValueChangedFcn = createCallbackFcn(app, @dopantFieldValueChanged, true);
            app.EEtEditField_3.Value = 0.2;
            app.DitEditField_6Label = uilabel(app.Defects1to6Panel);
            app.DitEditField_6Label.Text = 'ΔDit';
            app.DitEditField_6 = uieditfield(app.Defects1to6Panel, 'numeric');
            app.DitEditField_6.Limits = [-2 2];
            app.DitEditField_6.ValueChangedFcn = createCallbackFcn(app, @dopantFieldValueChanged, true);
            app.DitEditField_6.Value = 0.4;
            app.nEditField_4Label = uilabel(app.Defects1to6Panel);
            app.nEditField_4Label.Text = 'σ_n';
            app.nEditField_4 = uieditfield(app.Defects1to6Panel, 'numeric');
            app.nEditField_4.Limits = [1e-20 1e-10];
            app.nEditField_4.ValueChangedFcn = createCallbackFcn(app, @dopantFieldValueChanged, true);
            app.nEditField_4.Value = 1e-15;
            app.pEditField_3Label = uilabel(app.Defects1to6Panel);
            app.pEditField_3Label.Text = 'σ_p';
            app.pEditField_3 = uieditfield(app.Defects1to6Panel, 'numeric');
            app.pEditField_3.Limits = [1e-20 1e-10];
            app.pEditField_3.ValueChangedFcn = createCallbackFcn(app, @dopantFieldValueChanged, true);
            app.pEditField_3.Value = 1e-16;
            app.DitEditField_7Label = uilabel(app.Defects1to6Panel);
            app.DitEditField_7Label.Text = 'Dit';
            app.DitEditField_7 = uieditfield(app.Defects1to6Panel, 'numeric');
            app.DitEditField_7.Limits = [10000000 1e+18];
            app.DitEditField_7.ValueChangedFcn = createCallbackFcn(app, @dopantFieldValueChanged, true);
            app.DitEditField_7.Value = 100000000000000;
            app.EEtEditField_4Label = uilabel(app.Defects1to6Panel);
            app.EEtEditField_4Label.Text = 'E-Et';
            app.EEtEditField_4 = uieditfield(app.Defects1to6Panel, 'numeric');
            app.EEtEditField_4.Limits = [-1 1];
            app.EEtEditField_4.ValueChangedFcn = createCallbackFcn(app, @dopantFieldValueChanged, true);
            app.EEtEditField_4.Value = 0.56;
            app.DitEditField_8Label = uilabel(app.Defects1to6Panel);
            app.DitEditField_8Label.Text = 'ΔDit';
            app.DitEditField_8 = uieditfield(app.Defects1to6Panel, 'numeric');
            app.DitEditField_8.Limits = [-2 2];
            app.DitEditField_8.ValueChangedFcn = createCallbackFcn(app, @dopantFieldValueChanged, true);
            app.DitEditField_8.Value = 0.3;
            app.nEditField_5Label = uilabel(app.Defects1to6Panel);
            app.nEditField_5Label.Text = 'σ_n';
            app.nEditField_5 = uieditfield(app.Defects1to6Panel, 'numeric');
            app.nEditField_5.Limits = [1e-20 1e-10];
            app.nEditField_5.ValueChangedFcn = createCallbackFcn(app, @dopantFieldValueChanged, true);
            app.nEditField_5.Value = 2e-18;
            app.pEditField_4Label = uilabel(app.Defects1to6Panel);
            app.pEditField_4Label.Text = 'σ_p';
            app.pEditField_4 = uieditfield(app.Defects1to6Panel, 'numeric');
            app.pEditField_4.Limits = [1e-20 1e-10];
            app.pEditField_4.ValueChangedFcn = createCallbackFcn(app, @dopantFieldValueChanged, true);
            app.pEditField_4.Value = 2e-18;
            app.DitEditField_9Label = uilabel(app.Defects1to6Panel);
            app.DitEditField_9Label.Text = 'Dit';
            app.DitEditField_9 = uieditfield(app.Defects1to6Panel, 'numeric');
            app.DitEditField_9.Limits = [10000000 1e+18];
            app.DitEditField_9.ValueChangedFcn = createCallbackFcn(app, @dopantFieldValueChanged, true);
            app.DitEditField_9.Value = 10000000;
            app.EEtEditField_5Label = uilabel(app.Defects1to6Panel);
            app.EEtEditField_5Label.Text = 'E-Et';
            app.EEtEditField_5 = uieditfield(app.Defects1to6Panel, 'numeric');
            app.EEtEditField_5.Limits = [-1 1];
            app.EEtEditField_5.ValueChangedFcn = createCallbackFcn(app, @dopantFieldValueChanged, true);
            app.DitEditField_10Label = uilabel(app.Defects1to6Panel);
            app.DitEditField_10Label.Text = 'ΔDit';
            app.DitEditField_10 = uieditfield(app.Defects1to6Panel, 'numeric');
            app.DitEditField_10.Limits = [-2 2];
            app.DitEditField_10.ValueChangedFcn = createCallbackFcn(app, @dopantFieldValueChanged, true);
            app.DitEditField_10.Value = 1;
            app.nEditField_6Label = uilabel(app.Defects1to6Panel);
            app.nEditField_6Label.Text = 'σ_n';
            app.nEditField_6 = uieditfield(app.Defects1to6Panel, 'numeric');
            app.nEditField_6.Limits = [1e-20 1e-10];
            app.nEditField_6.ValueChangedFcn = createCallbackFcn(app, @dopantFieldValueChanged, true);
            app.nEditField_6.Value = 1e-15;
            app.pEditField_5Label = uilabel(app.Defects1to6Panel);
            app.pEditField_5Label.Text = 'σ_p';
            app.pEditField_5 = uieditfield(app.Defects1to6Panel, 'numeric');
            app.pEditField_5.Limits = [1e-20 1e-10];
            app.pEditField_5.ValueChangedFcn = createCallbackFcn(app, @dopantFieldValueChanged, true);
            app.pEditField_5.Value = 1e-16;
            app.typeDropDown_6Label = uilabel(app.Defects1to6Panel);
            app.typeDropDown_6Label.Visible = 'off';
            app.typeDropDown_6Label.Text = 'type';
            app.typeDropDown_6 = uidropdown(app.Defects1to6Panel);
            app.typeDropDown_6.Items = {'Inactive', 'Don -tail', 'Acc -tail', 'Acc -tophat', 'Don -tophat', 'Acc -gauss', 'Don -gauss'};
            app.typeDropDown_6.ValueChangedFcn = createCallbackFcn(app, @dopantFieldValueChanged, true);
            app.typeDropDown_6.Value = 'Inactive';
            app.DitEditField_12Label = uilabel(app.Defects1to6Panel);
            app.DitEditField_12Label.Text = 'Dit';
            app.DitEditField_12 = uieditfield(app.Defects1to6Panel, 'numeric');
            app.DitEditField_12.Limits = [10000000 1e+18];
            app.DitEditField_12.ValueChangedFcn = createCallbackFcn(app, @dopantFieldValueChanged, true);
            app.DitEditField_12.Value = 10000000;
            app.nEditField_7Label = uilabel(app.Defects1to6Panel);
            app.nEditField_7Label.Text = 'σ_n';
            app.nEditField_7 = uieditfield(app.Defects1to6Panel, 'numeric');
            app.nEditField_7.Limits = [1e-20 1e-10];
            app.nEditField_7.ValueChangedFcn = createCallbackFcn(app, @dopantFieldValueChanged, true);
            app.nEditField_7.Value = 1e-15;
            app.pEditField_6Label = uilabel(app.Defects1to6Panel);
            app.pEditField_6Label.Text = 'σ_p';
            app.pEditField_6 = uieditfield(app.Defects1to6Panel, 'numeric');
            app.pEditField_6.Limits = [1e-20 1e-10];
            app.pEditField_6.ValueChangedFcn = createCallbackFcn(app, @dopantFieldValueChanged, true);
            app.pEditField_6.Value = 1e-16;
            app.EEtEditField_7Label = uilabel(app.Defects1to6Panel);
            app.EEtEditField_7Label.Text = 'E-Et';
            app.EEtEditField_6 = uieditfield(app.Defects1to6Panel, 'numeric');
            app.EEtEditField_6.Limits = [-1 1];
            app.EEtEditField_6.ValueChangedFcn = createCallbackFcn(app, @dopantFieldValueChanged, true);
            app.DitEditField_13Label = uilabel(app.Defects1to6Panel);
            app.DitEditField_13Label.Text = 'ΔDit';
            app.DitEditField_11 = uieditfield(app.Defects1to6Panel, 'numeric');
            app.DitEditField_11.Limits = [-2 2];
            app.DitEditField_11.ValueChangedFcn = createCallbackFcn(app, @dopantFieldValueChanged, true);
            app.DitEditField_11.Value = 1;
            app.ButtonGroup = uibuttongroup(app.Defects1to6Panel);
            app.ChargedButton = uiradiobutton(app.ButtonGroup);
            app.ChargedButton.Text = 'Charged';
            app.ChargedButton.Value = true;
            app.NeutralButton = uiradiobutton(app.ButtonGroup);
            app.NeutralButton.Text = 'Neutral';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '-----------------------------------------';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '-----------------------------------------';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '__________________________';
            app.pointsLabel = uilabel(app.UIFigure);
            app.pointsLabel.Text = '#points';
            app.NumSweepEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.NumSweepEditField_2.Limits = [1 200];
            app.NumSweepEditField_2.ValueDisplayFormat = '%.0f';
            app.NumSweepEditField_2.Value = 10;
            app.Sn0cmsEditFieldLabel = uilabel(app.UIFigure);
            app.Sn0cmsEditFieldLabel.Text = 'Sn0 (cm/s)';
            app.Sn0cmsEditField = uieditfield(app.UIFigure, 'numeric');
            app.Sn0cmsEditField.ValueDisplayFormat = '%2.2f';
            app.Sn0cmsEditField.Editable = 'off';
            app.Sp0cmsEditFieldLabel = uilabel(app.UIFigure);
            app.Sp0cmsEditFieldLabel.Text = 'Sp0 (cm/s)';
            app.Sp0cmsEditField = uieditfield(app.UIFigure, 'numeric');
            app.Sp0cmsEditField.ValueDisplayFormat = '%2.2f';
            app.Sp0cmsEditField.Editable = 'off';
            app.J0sfAcm2EditFieldLabel = uilabel(app.UIFigure);
            app.J0sfAcm2EditFieldLabel.Text = 'J0s (fA/cm2)';
            app.J0sfAcm2EditField = uieditfield(app.UIFigure, 'numeric');
            app.J0sfAcm2EditField.ValueDisplayFormat = '%2.4f';
            app.J0sfAcm2EditField.Editable = 'off';
            app.DnorQfrangeLabel = uilabel(app.UIFigure);
            app.DnorQfrangeLabel.Text = 'Dn or Qf range';
            app.DitLabel_2 = uilabel(app.UIFigure);
            app.DitLabel_2.Text = 'Dit';
            app.WithMetalEffectsLabel = uilabel(app.UIFigure);
            app.WithMetalEffectsLabel.Text = 'With Metal Effects:';
            app.surfVEditFieldLabel = uilabel(app.UIFigure);
            app.surfVEditFieldLabel.Text = 'φ_surf (V)';
            app.surfVEditField = uieditfield(app.UIFigure, 'numeric');
            app.surfVEditField.ValueDisplayFormat = '%2.2f';
            app.surfVEditField.Editable = 'off';
            app.n_s1cm3EditFieldLabel = uilabel(app.UIFigure);
            app.n_s1cm3EditFieldLabel.Text = 'n_s(1/cm3)';
            app.n_s1cm3EditField = uieditfield(app.UIFigure, 'numeric');
            app.n_s1cm3EditField.ValueDisplayFormat = '%2.2e';
            app.n_s1cm3EditField.Editable = 'off';
            app.p_s1cm3EditFieldLabel = uilabel(app.UIFigure);
            app.p_s1cm3EditFieldLabel.Text = 'p_s(1/cm3)';
            app.p_s1cm3EditField = uieditfield(app.UIFigure, 'numeric');
            app.p_s1cm3EditField.ValueDisplayFormat = '%2.2e';
            app.p_s1cm3EditField.Editable = 'off';
            app.SeffcmsEditField_2Label = uilabel(app.UIFigure);
            app.SeffcmsEditField_2Label.Text = 'Seff (cm/s)';
            app.SeffcmsEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.SeffcmsEditField_2.ValueDisplayFormat = '%2.4f';
            app.SeffcmsEditField_2.Editable = 'off';
            app.J0sfAcm2EditField_2Label = uilabel(app.UIFigure);
            app.J0sfAcm2EditField_2Label.Text = 'J0s (fA/cm2)';
            app.J0sfAcm2EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.J0sfAcm2EditField_2.ValueDisplayFormat = '%2.4f';
            app.J0sfAcm2EditField_2.Editable = 'off';
            app.n_s1cm3EditField_2Label = uilabel(app.UIFigure);
            app.n_s1cm3EditField_2Label.Text = 'n_s(1/cm3)';
            app.n_s1cm3EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.n_s1cm3EditField_2.ValueDisplayFormat = '%2.2e';
            app.n_s1cm3EditField_2.Editable = 'off';
            app.p_s1cm3EditField_2Label = uilabel(app.UIFigure);
            app.p_s1cm3EditField_2Label.Text = 'p_s(1/cm3)';
            app.p_s1cm3EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.p_s1cm3EditField_2.ValueDisplayFormat = '%2.2e';
            app.p_s1cm3EditField_2.Editable = 'off';
            app.Q_geffqcm2EditFieldLabel = uilabel(app.UIFigure);
            app.Q_geffqcm2EditFieldLabel.Text = 'Q_g-eff(q/cm2)';
            app.Q_geffqcm2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Q_geffqcm2EditField.ValueDisplayFormat = '%2.2e';
            app.Q_geffqcm2EditField.Editable = 'off';
            app.surfVEditField_2Label = uilabel(app.UIFigure);
            app.surfVEditField_2Label.Text = 'φ_surf (V)';
            app.surfVEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.surfVEditField_2.ValueDisplayFormat = '%2.2f';
            app.surfVEditField_2.Editable = 'off';
            app.SweepmsButton = uibutton(app.UIFigure, 'push');
            app.SweepmsButton.ButtonPushedFcn = createCallbackFcn(app, @SweepmsButtonPushed, true);
            app.SweepmsButton.Text = 'Sweep ΔΦms';
            app.UnderLightCheckBox = uicheckbox(app.UIFigure);
            app.UnderLightCheckBox.ValueChangedFcn = createCallbackFcn(app, @dopantFieldValueChanged, true);
            app.UnderLightCheckBox.Text = 'Under Light';
            app.UnderLightCheckBox.Value = true;
            app.SPVcalulationsLabel = uilabel(app.UIFigure);
            app.SPVcalulationsLabel.Text = 'SPV calulations:';
            app.estimateKPButton = uibutton(app.UIFigure, 'push');
            app.estimateKPButton.ButtonPushedFcn = createCallbackFcn(app, @estimateKPButtonPushed, true);
            app.estimateKPButton.Text = 'estimate KP';
            app.Gen_lightcm3EditFieldLabel = uilabel(app.UIFigure);
            app.Gen_lightcm3EditFieldLabel.Text = 'Gen_light(#/cm3)';
            app.Gen_lightcm3EditField = uieditfield(app.UIFigure, 'numeric');
            app.Gen_lightcm3EditField.Limits = [0 Inf];
            app.TbulksrhsEditFieldLabel = uilabel(app.UIFigure);
            app.TbulksrhsEditFieldLabel.Text = 'Tbulk-srh(s)';
            app.TbulksrhsEditField = uieditfield(app.UIFigure, 'numeric');
            app.TbulksrhsEditField.Limits = [0 Inf];
            app.TbulksrhsEditField.Value = 100;
            app.CPDdarkEditFieldLabel = uilabel(app.UIFigure);
            app.CPDdarkEditFieldLabel.Text = 'CPDdark';
            app.CPDdarkEditField = uieditfield(app.UIFigure, 'numeric');
            app.CPDdarkEditField.Value = -0.2;
            app.CPDlightEditFieldLabel = uilabel(app.UIFigure);
            app.CPDlightEditFieldLabel.Text = 'CPDlight';
            app.CPDlightEditField = uieditfield(app.UIFigure, 'numeric');
            app.CPDlightEditField.Value = -0.5;
            app.WorkingLampLabel = uilabel(app.UIFigure);
            app.WorkingLampLabel.Text = 'Working';
            app.WorkingLamp = uilamp(app.UIFigure);
            app.ExportDefectsButton = uibutton(app.UIFigure, 'push');
            app.ExportDefectsButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDefectsButtonPushed, true);
            app.ExportDefectsButton.Text = 'Export Defects';
            app.XcentroidnmEditFieldLabel = uilabel(app.UIFigure);
            app.XcentroidnmEditFieldLabel.Text = 'Xcentroid (nm)';
            app.XcentroidnmEditField = uieditfield(app.UIFigure, 'numeric');
            app.XcentroidnmEditField.Limits = [0.01 1000];
            app.XcentroidnmEditField.ValueDisplayFormat = '%1.1f';
            app.XcentroidnmEditField.Value = 1;
            app.CPDdarkEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.CPDdarkEditField_2.Value = -0.1;
            app.CPDlightEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.CPDlightEditField_2.Value = -0.4;
            app.iVocmVEditFieldLabel = uilabel(app.UIFigure);
            app.iVocmVEditFieldLabel.Text = 'iVoc (mV)';
            app.iVocmVEditField = uieditfield(app.UIFigure, 'numeric');
            app.iVocmVEditField.ValueDisplayFormat = '%2.4f';
            app.iVocmVEditField.Editable = 'off';
            app.ImportButton = uibutton(app.UIFigure, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.PlotImportedButton = uibutton(app.UIFigure, 'push');
            app.PlotImportedButton.ButtonPushedFcn = createCallbackFcn(app, @PlotImportedButtonPushed, true);
            app.PlotImportedButton.Text = 'Plot Imported';
            app.SnEffcmsEditFieldLabel = uilabel(app.UIFigure);
            app.SnEffcmsEditFieldLabel.Text = 'SnEff (cm/s)';
            app.SnEffcmsEditField = uieditfield(app.UIFigure, 'numeric');
            app.SnEffcmsEditField.ValueDisplayFormat = '%2.2f';
            app.SnEffcmsEditField.Editable = 'off';
            app.SpEffcmsEditFieldLabel = uilabel(app.UIFigure);
            app.SpEffcmsEditFieldLabel.Text = 'SpEff (cm/s)';
            app.SpEffcmsEditField = uieditfield(app.UIFigure, 'numeric');
            app.SpEffcmsEditField.ValueDisplayFormat = '%2.2f';
            app.SpEffcmsEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = srv_app25

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end